classdef BedformFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BedformFinderUIFigure          matlab.ui.Figure
        Single                         matlab.ui.container.Panel
        singleGrid                     matlab.ui.container.GridLayout
        FolderLocationField_2          matlab.ui.control.EditField
        EnterFilenameField_2           matlab.ui.control.EditField
        ExtDropDown_2                  matlab.ui.control.DropDown
        ExtLabel_2                     matlab.ui.control.Label
        ExtLabel                       matlab.ui.control.Label
        DataFileLabel                  matlab.ui.control.Label
        LineFileLabel                  matlab.ui.control.Label
        RiverLineDropDown              matlab.ui.control.DropDown
        PleasechooseareferencelineButton  matlab.ui.control.Button
        InputCRSLabel                  matlab.ui.control.Label
        DefaultButton_2                matlab.ui.control.Button
        singleLog                      matlab.ui.control.HTML
        ThresholdAutoButton            matlab.ui.control.StateButton
        ProminenceAutoButton           matlab.ui.control.StateButton
        SampleSpacingAutoButton        matlab.ui.control.StateButton
        OffsetDistanceAutoButton       matlab.ui.control.StateButton
        ThresholdHPFEditField          matlab.ui.control.NumericEditField
        ThresholdHPFmLabel             matlab.ui.control.Label
        ProminenceEditField            matlab.ui.control.NumericEditField
        ProminencemEditFieldLabel      matlab.ui.control.Label
        OffsetDistanceEditField        matlab.ui.control.NumericEditField
        OffsetDistancemLabel           matlab.ui.control.Label
        SampleSpacingEditField         matlab.ui.control.NumericEditField
        SampleSpacingmLabel            matlab.ui.control.Label
        ComputeButton                  matlab.ui.control.Button
        VerticalTypeDropDown           matlab.ui.control.DropDown
        VerticalTypeLabel              matlab.ui.control.Label
        VerticalUnitsDropDown          matlab.ui.control.DropDown
        VerticalUnitsLabel             matlab.ui.control.Label
        EPSGEditField                  matlab.ui.control.NumericEditField
        EPSGLabel                      matlab.ui.control.Label
        InputCRSDropDown               matlab.ui.control.DropDown
        SaveFiguresButton              matlab.ui.control.Button
        SaveDataButton                 matlab.ui.control.Button
        EnterFilenameField             matlab.ui.control.EditField
        ExtDropDown                    matlab.ui.control.DropDown
        PleasechooseadatafileButton    matlab.ui.control.Button
        backHomeButton2                matlab.ui.control.Button
        Batch                          matlab.ui.container.Panel
        batchGrid                      matlab.ui.container.GridLayout
        ButtonGroup                    matlab.ui.container.ButtonGroup
        FilesButton                    matlab.ui.control.ToggleButton
        FolderButton                   matlab.ui.control.ToggleButton
        VerticalTypeLabel_2            matlab.ui.control.Label
        VerticalUnitsLabel_2           matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        ThresholdHPFmLabel_2           matlab.ui.control.Label
        ProminencemLabel               matlab.ui.control.Label
        SampleSpacingmLabel_2          matlab.ui.control.Label
        OffsetDistancemLabel_2         matlab.ui.control.Label
        EPSGLabel_2                    matlab.ui.control.Label
        FolderLocationField_4          matlab.ui.control.EditField
        ExtLabel_3                     matlab.ui.control.Label
        DataLabel                      matlab.ui.control.Label
        LineFileLabel_2                matlab.ui.control.Label
        RiverLineDropDown_2            matlab.ui.control.DropDown
        PleasechooseareferencelineButton_2  matlab.ui.control.Button
        InputCRSLabel_2                matlab.ui.control.Label
        DefaultButton_5                matlab.ui.control.Button
        batchLog                       matlab.ui.control.HTML
        ThresholdAutoButton_2          matlab.ui.control.StateButton
        ProminenceAutoButton_2         matlab.ui.control.StateButton
        SampleSpacingAutoButton_2      matlab.ui.control.StateButton
        OffsetDistanceAutoButton_2     matlab.ui.control.StateButton
        ThresholdHPFEditField_2        matlab.ui.control.NumericEditField
        ProminenceEditField_2          matlab.ui.control.NumericEditField
        OffsetDistanceEditField_2      matlab.ui.control.NumericEditField
        SampleSpacingEditField_2       matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        VerticalTypeDropDown_2         matlab.ui.control.DropDown
        VerticalUnitsDropDown_2        matlab.ui.control.DropDown
        EPSGEditField_2                matlab.ui.control.NumericEditField
        InputCRSDropDown_2             matlab.ui.control.DropDown
        ExtDropDown_4                  matlab.ui.control.DropDown
        PleasechooseadatafolderButton  matlab.ui.control.Button
        backHomeButton3                matlab.ui.control.Button
        Analysis                       matlab.ui.container.Panel
        analysisGrid                   matlab.ui.container.GridLayout
        CreateLineButton               matlab.ui.control.Button
        ClipDataButton                 matlab.ui.control.Button
        GroupPlottingButton            matlab.ui.control.Button
        SinglePlottingButton           matlab.ui.control.Button
        backHomeButton5                matlab.ui.control.Button
        Home                           matlab.ui.container.Panel
        homeGrid                       matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        setSettingsButton              matlab.ui.control.Button
        Title                          matlab.ui.control.Label
        setAnalysisButton              matlab.ui.control.Button
        setGroupButton                 matlab.ui.control.Button
        setBatchButton                 matlab.ui.control.Button
        setSingleButton                matlab.ui.control.Button
        seteHydroButton                matlab.ui.control.Button
        eHydro                         matlab.ui.container.Panel
        eHydroGrid                     matlab.ui.container.GridLayout
        Stop                           matlab.ui.control.Button
        Pause                          matlab.ui.control.StateButton
        FolderLocationField            matlab.ui.control.EditField
        eHydroLog                      matlab.ui.control.HTML
        YmaxLabel                      matlab.ui.control.Label
        XmaxLabel                      matlab.ui.control.Label
        YminLabel                      matlab.ui.control.Label
        XminLabel                      matlab.ui.control.Label
        FolderLocationsubfolderswillbecreatedforeachrivercodeLabel  matlab.ui.control.Label
        RiverCodeEditor                matlab.ui.control.EditField
        RiverDropDown                  matlab.ui.control.DropDown
        RiverLabel                     matlab.ui.control.Label
        QuantityAllButton              matlab.ui.control.StateButton
        QuantityEditField              matlab.ui.control.NumericEditField
        QuantityLabel                  matlab.ui.control.Label
        DownloadButton                 matlab.ui.control.Button
        FetchButton                    matlab.ui.control.Button
        DefaultButton                  matlab.ui.control.Button
        EndDateDatePicker              matlab.ui.control.DatePicker
        EndDateLabel                   matlab.ui.control.Label
        StartDateDatePicker            matlab.ui.control.DatePicker
        StartDateLabel                 matlab.ui.control.Label
        YmaxField                      matlab.ui.control.NumericEditField
        XmaxField                      matlab.ui.control.NumericEditField
        YminField                      matlab.ui.control.NumericEditField
        XminField                      matlab.ui.control.NumericEditField
        backHomeButton1                matlab.ui.control.Button
        eHydroMap                      matlab.ui.control.UIAxes
        CreateLine                     matlab.ui.container.Panel
        createLineGrid                 matlab.ui.container.GridLayout
        TipDoubleclickrightclickorpressentertofinishdrawinglineLabel  matlab.ui.control.Label
        SaveDirectoryLabel             matlab.ui.control.Label
        FolderLocationField_7          matlab.ui.control.EditField
        DefaultButton_8                matlab.ui.control.Button
        ClearPlotButton_2              matlab.ui.control.Button
        SavePointsButton               matlab.ui.control.Button
        DrawLineButton                 matlab.ui.control.Button
        GenerateRasterButton           matlab.ui.control.Button
        PleasechooseadatafileButton_5  matlab.ui.control.Button
        BackButton_4                   matlab.ui.control.Button
        RawDataRaster                  matlab.ui.control.UIAxes
        ClipData                       matlab.ui.container.Panel
        clipDataGrid                   matlab.ui.container.GridLayout
        ClearPlotButton                matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ClipButton                     matlab.ui.control.Button
        AddMaskButton                  matlab.ui.control.Button
        GeneratePeaksonRasterButton    matlab.ui.control.Button
        PleasechooseadatafileButton_3  matlab.ui.control.Button
        GroupIndexEditField_2          matlab.ui.control.NumericEditField
        GroupIndexLabel_2              matlab.ui.control.Label
        BackButton                     matlab.ui.control.Button
        PeaksOnRasterForClipping       matlab.ui.control.UIAxes
        Settings                       matlab.ui.container.Panel
        settingsGrid                   matlab.ui.container.GridLayout
        ThemeSettingsTogglebetweenDarkandLightmodeLabel  matlab.ui.control.Label
        SetDarkThemeButton             matlab.ui.control.Button
        FigureSettingsSingleanalysiscontrolandfigureresolutionDPILabel  matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        DisableSingleAnalysisFigCreationButton  matlab.ui.control.StateButton
        DisableParpoolButton           matlab.ui.control.StateButton
        DataandFigureExtensionsThischangespreferencesforexportextensionsLabel  matlab.ui.control.Label
        DropDown2                      matlab.ui.control.DropDown
        DropDown                       matlab.ui.control.DropDown
        ParpoolSettingsParallelProcessesThisdictatesparpoolenablingandworkercountLabel  matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        DefaultSaveDirectorySetting    matlab.ui.control.EditField
        DefaultSaveDirectoryThisistheDefaultfolderonotherpagesLabel  matlab.ui.control.Label
        DefaultButton_4                matlab.ui.control.Button
        backHomeButton7                matlab.ui.control.Button
        SinglePlot                     matlab.ui.container.Panel
        singlePlotGrid                 matlab.ui.container.GridLayout
        RawBathymetryButton            matlab.ui.control.Button
        ProfileIndexEditField          matlab.ui.control.NumericEditField
        ProfileIndexLabel              matlab.ui.control.Label
        BedElevationProfileButton      matlab.ui.control.Button
        FolderLocationField_5          matlab.ui.control.EditField
        EnterFilenameField_3           matlab.ui.control.EditField
        ExtDropDown_5                  matlab.ui.control.DropDown
        ExtLabel_4                     matlab.ui.control.Label
        DefaultButton_6                matlab.ui.control.Button
        SaveFiguresButton_2            matlab.ui.control.Button
        PeaksonRasterButton            matlab.ui.control.Button
        SteepnessHeatmapButton         matlab.ui.control.Button
        HeightHeatmapButton            matlab.ui.control.Button
        LengthHeatmapButton            matlab.ui.control.Button
        SteepnessPDFButton             matlab.ui.control.Button
        HeightPDFButton                matlab.ui.control.Button
        LengthPDFButton                matlab.ui.control.Button
        PleasechooseadatafileButton_2  matlab.ui.control.Button
        GroupIndexEditField            matlab.ui.control.NumericEditField
        GroupIndexLabel                matlab.ui.control.Label
        BackButton_3                   matlab.ui.control.Button
        GroupPlot                      matlab.ui.container.Panel
        groupPlotGrid                  matlab.ui.container.GridLayout
        DropDown_2                     matlab.ui.control.DropDown
        HeightvsLengthButton           matlab.ui.control.Button
        SteepnessScaleRelationButton   matlab.ui.control.Button
        HeightScaleRelationButton      matlab.ui.control.Button
        LengthScaleRelationButton      matlab.ui.control.Button
        ReachLocationvsDateButton      matlab.ui.control.Button
        FolderLocationField_6          matlab.ui.control.EditField
        EnterFilenameField_4           matlab.ui.control.EditField
        ExtDropDown_6                  matlab.ui.control.DropDown
        ExtLabel_5                     matlab.ui.control.Label
        DefaultButton_7                matlab.ui.control.Button
        SaveFiguresButton_3            matlab.ui.control.Button
        PleasechooseadatafileButton_4  matlab.ui.control.Button
        BackButton_2                   matlab.ui.control.Button
        Group                          matlab.ui.container.Panel
        groupGrid                      matlab.ui.container.GridLayout
        FolderLocationField_3          matlab.ui.control.EditField
        ClearButton                    matlab.ui.control.Button
        groupLog                       matlab.ui.control.HTML
        GroupButton                    matlab.ui.control.Button
        ExtDropDown_3                  matlab.ui.control.DropDown
        ExtDropDown_3Label             matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        DefaultButton_3                matlab.ui.control.Button
        AddFilesButton                 matlab.ui.control.Button
        RemoveSelectedButton           matlab.ui.control.Button
        FileListBox                    matlab.ui.control.ListBox
        backHomeButton4                matlab.ui.control.Button
    end

    
    properties (Access = public)
        settings = struct()                     % structure for settings
        initialeHydroPageSettings = struct()    % structure for eHydro page
        initialSinglePageSettings = struct()    % structure for single page
        initialBatchPageSettings = struct()     % structure for batch page
        initialGroupPageSettings = struct()     % structure for group page
        initialSinglePlotPageSettings = struct()% structure for single plot page
        initialClipDataPageSettings = struct()  % structure for clip data page
        initialBatchPlotPageSettings = struct() % structure for batch plot page
        initialCreateLinePageSettings = struct()% structure for create line page
        eHydroLogContent = " ";                 % eHydro log variable
        singleLogContent = " ";                 % single log variable
        batchLogContent = " ";                  % batch log variable
        groupLogContent = " ";                  % group log variable
        filenames = [];                         % filenames array for eHydro query
        projections = [];                       % coordinate systems array for eHydro query
        urls = [];                              % url array for eHydro query
        bboxLines;                              % bounding box handle
        downloadLoopRunning = false;            % eHydro download loop start flag
        downloadLoopCancel = false;             % eHydro download loop end flag
        batchLoopCancel = false;                % batch loop cancel flag
        batchIsParallel = false;                % flag specifying if parallel batch is selected
        batchIsReady = true;                    % flag specifying whether batch worker have been set
        batchRunFlag = false;                   % batch loop start flag
        stopBatchFlag = false;                  % batch loop end flag
        filePathGroupList = [];                 % group filename list
        calculatedMaxParpoolWorkers = 1;        % max worker count
        batchFilenames = [];                    % filenames to be run in batch
        D = [];                                 % temporary data variable for processing and plotting
        masks = [];                             % array of masks for clipping
        requiredFields = {
                    'L';
                    'H';
                    'h';
                    'Filename';
                    'Date';
                    'RiverKmStart';
                    'RiverKmEnd';
                    'RiverMileStart';
                    'RiverMileEnd';
                    'ProfileL';
                    'ProfileH';
                    'Profileh';
                    'ProfileRiverMile';
                    'ProfileRiverKm';
                    'ProfileEastingPeak';
                    'ProfileNorthingPeak';
                    'ProfileEastingTrough';
                    'ProfileNorthingTrough';
                    'ProfileInfo';
                    'ProfileDuneInfo';
                    'Parameters';
                    'RawData'
                };                              % required fields for structure
        defaultSettings = struct('appdirectory', "", ...
                                 'defaultDataType', ".mat", ...
                                 'defaultFigureType', ".pdf", ...
                                 'generateFigures', true, ...
                                 'figResolution', 300, ...
                                 'useParPool', true, ...
                                 'parPoolWorkers', 8, ...
                                 'theme', "Light" ...
                                 );             % Default set of values for settings
        themeData = struct()                    % Colors for component theme
        points = [];                            % points for line creation
    end
    
    methods (Access = public)

        function addpaths(~)
            % Path locations to dependancies
                addpath("App Dependancies\")
                addpath("App Dependancies\Images\")
                addpath("App Dependancies\RiverLines\")
                addpath("App Dependancies\Shape Files\")
                addpath("FunctionToolbox\")
                addpath("FunctionToolbox\Plotting\")
                addpath("FunctionToolbox\BedformIdentification\")
                addpath("FunctionToolbox\BedformProcessing\")
        end

        function setCWD(~)
            % Set working directory upon launch
                fullPath = mfilename('fullpath');
                targetDir = fileparts(fullPath);
                
                if ~strcmp(pwd, targetDir)
                    cd(targetDir);
                end
        end

        function clearWindow(app)
            % Clear window for next panel
                panels = findall(app.BedformFinderUIFigure, 'Type', 'uipanel');
                set(panels, 'Visible', 'off');
        end

        function setHome(app)
            clearWindow(app)
            app.Home.Visible = "on";
            drawnow;
        end

        function seteHydro(app)
            clearWindow(app)
            app.eHydro.Visible = "on";
            drawnow;
        end

        function setSingle(app)
            clearWindow(app)
            app.Single.Visible = "on";
            drawnow;
        end

        function setBatch(app)
            clearWindow(app)
            app.Batch.Visible = "on";
            drawnow;
        end

        function setGroup(app)
            clearWindow(app)
            app.Group.Visible = "on";
            drawnow;
        end

        function setAnalysis(app)
            clearWindow(app)
            app.Analysis.Visible = "on";
            drawnow;
        end

        function setSettings(app)
            clearWindow(app)
            app.Settings.Visible = "on";
            drawnow;
        end

        function setSinglePlot(app)
            clearWindow(app)
            app.SinglePlot.Visible = "on";
            drawnow;
        end

        function setGroupPlot(app)
            clearWindow(app)
            app.GroupPlot.Visible = "on";
            drawnow;
        end

        function setClipData(app)
            clearWindow(app)
            app.ClipData.Visible = "on";
            drawnow;
        end

        function setCreateLine(app)
            clearWindow(app)
            app.CreateLine.Visible = "on";
            drawnow;
        end

        function updateGroupText(app,newText)
            % Get CSS
                css = getThemeCSS(app, app.themeData.(app.settings.theme));
            % Create text for group page
                newLine = "<div>" + string(newText) + "</div>";
                app.groupLogContent = app.groupLogContent + newLine;
            % Auto scroll to bottom
                html = css + app.groupLogContent + "<script>window.scrollTo(0, document.body.scrollHeight);</script>";
            % Add text to page
                app.groupLog.HTMLSource = html;
            % UI update
                drawnow
        end

        function updateSingleText(app,newText)
            % Get CSS
                css = getThemeCSS(app, app.themeData.(app.settings.theme));
            % Create text for single page
                newLine = "<div>" + string(newText) + "</div>";
                app.singleLogContent = app.singleLogContent + newLine;
            % Auto scroll to bottom
                html = css + app.singleLogContent + "<script>window.scrollTo(0, document.body.scrollHeight);</script>";
            % Add text to page
                app.singleLog.HTMLSource = html;
            % UI update
                drawnow
        end

        function updateBatchText(app,newText)
            % Get CSS
                css = getThemeCSS(app, app.themeData.(app.settings.theme));
            % Create text for batch page
                newLine = "<div>" + string(newText) + "</div>";
                app.batchLogContent = app.batchLogContent + newLine;
            % Auto scroll to bottom
                html = css + app.batchLogContent + "<script>window.scrollTo(0, document.body.scrollHeight);</script>";
            % Add text to page
                app.batchLog.HTMLSource = html;
            % UI update
                drawnow
        end

        function updateeHydroText(app,newText)
            % Get CSS
                css = getThemeCSS(app, app.themeData.(app.settings.theme));
            % Create text for eHydro page
                newLine = "<div>" + string(newText) + "</div>";
                app.eHydroLogContent = app.eHydroLogContent + newLine;
            % Auto scroll to bottom
                html = css + app.eHydroLogContent + "<script>window.scrollTo(0, document.body.scrollHeight);</script>";
            % Add text to page
                app.eHydroLog.HTMLSource = html;
            % UI update
                drawnow
        end

        function documentsFolder = getDocumentsFolder(~)
            % Get personal one drive folder
                personalOneDrive = getenv("OneDriveConsumer");
            % Get commercial one drive folder
                oneDriveRoot = getenv("OneDrive");
            % If personal one drive exists and has a documents folder, set as app documents folder
                if ~isempty(personalOneDrive) && isfolder(fullfile(personalOneDrive, 'Documents'))
                    documentsFolder = fullfile(personalOneDrive, 'Documents');
            % If commercial one drive exists and has a documents folder, set as app documents folder
                elseif ~isempty(oneDriveRoot) && isfolder(fullfile(oneDriveRoot, 'Documents'))
                    documentsFolder = fullfile(oneDriveRoot, 'Documents');
            % If no one drive folder exists but user has documents folder, set as app documents folder
                elseif isfolder(fullfile(getenv('USERPROFILE'), 'Documents'))
                    documentsFolder = fullfile(getenv('USERPROFILE'), 'Documents');
            % Else make and set documents folder in user root
                else
                    mkdir(fullfile(getenv('USERPROFILE'), 'Documents'));
                    documentsFolder = fullfile(getenv('USERPROFILE'), 'Documents');
                end
        end

        function initBoundingBox(app)
            % UI component
                ax = app.eHydroMap;
            
            % Prepare handles for plot updates
                app.bboxLines(1) = plot(ax, NaN, NaN, 'r', 'LineWidth', 2);
                app.bboxLines(2) = plot(ax, NaN, NaN, 'r', 'LineWidth', 2);
                app.bboxLines(3) = plot(ax, NaN, NaN, 'r', 'LineWidth', 2);
                app.bboxLines(4) = plot(ax, NaN, NaN, 'r', 'LineWidth', 2);
        end

        function drawBoundingBox(app)
            % Get bounding box values from numeric fields
                xmin = app.XminField.Value;
                ymin = app.YminField.Value;
                xmax = app.XmaxField.Value;
                ymax = app.YmaxField.Value;
            % Set the new bounding box values
                set(app.bboxLines(1), 'XData',[xmin xmax], 'YData',[ymin ymin]);
                set(app.bboxLines(2), 'XData',[xmin xmax], 'YData',[ymax ymax]);
                set(app.bboxLines(3), 'XData',[xmin xmin], 'YData',[ymin ymax]);
                set(app.bboxLines(4), 'XData',[xmax xmax], 'YData',[ymin ymax]);
        end

        function loadMap(app)
            % Read in United States map shape file
                S = shaperead('.\App Dependancies\Shape Files\cb_2021_us_state_5m.shp', 'UseGeoCoords', true);
            % Get UI component
                ax = app.eHydroMap;
                hold(ax,'on');
            % Plot the United States map shape file
                for k = 1:numel(S)
                    pg = polyshape(S(k).Lon, S(k).Lat, 'Simplify', false);
                    plot(ax, pg, ...
                        'FaceColor', [1 1 1], ...
                        'FaceAlpha', 0.85, ...
                        'EdgeColor', 'k');
                end
            % Plot all saved rivers
                plotRiversFromFile(app,ax)
            % Plot invisible padding to prevent UI Axis reshaping
                pad = 1000;
                plot(ax, [-pad pad], [-pad -pad], 'w');
                plot(ax, [-pad pad], [pad pad], 'w');
                plot(ax, [-pad -pad], [-pad pad], 'w');
                plot(ax, [pad pad], [-pad pad], 'w');
            % Initialize and set the bounding box
                initBoundingBox(app)
                drawBoundingBox(app)
            % UI Axis viewing parameters
                axis(ax,'image');
                box(ax,"on")
            % Set limits around center of US
                latCenter = 38.5;
                lonCenter = -98;
                xlim(ax,[lonCenter-23,lonCenter+23])
                ylim(ax,[latCenter-15,latCenter+15])
            % Remove labels and ticks for clean map
                ax.XLabel.String = '';
                ax.YLabel.String = '';
                ax.Title.String = '';
                ax.XTick = [];
                ax.YTick = [];
        end

        function plotRiversFromFile(app, ax)
            % Read in data saved for all rivers
                S = load('.\App Dependancies\Shape Files\AllRiverMileMarkers.mat');
            % Get proper fieldnames
                rivers = S.rivers;
                names = fieldnames(rivers);
            % Plot each river
                for i = 1:numel(names)
                    r = rivers.(names{i});
                    h = plot(ax, r.lon, r.lat, '.-', 'Color', [0.02 0.25 0.4]);
                    h.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('River Mile', r.mile);
                    h.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('River', repmat(replace(names(i),'_',' '), numel(r.mile), 1));
                end
        end

        function calculateParpoolWorkers(app)
            % Get CPU core count and available system DRAM
                numCores = feature('numcores');
                [~,sysMemBytes] = memory();
            % Calculate maximum allowable workers from values
                sysMemGB = sysMemBytes.PhysicalMemory.Total / 1e9;
                maxWorkers = floor(min(numCores-1, floor(sysMemGB/4))*2/3);  
                app.calculatedMaxParpoolWorkers = min(max(maxWorkers,1),12);
        end

        function saveeHydroPageSettings(app)
            app.initialeHydroPageSettings.startDate = app.StartDateDatePicker.Value;
            app.initialeHydroPageSettings.endDate = app.EndDateDatePicker.Value;
            app.initialeHydroPageSettings.riverOption = app.RiverDropDown.Value;
            app.initialeHydroPageSettings.riverCode = app.RiverCodeEditor.Value;
            app.initialeHydroPageSettings.quantity = app.QuantityEditField.Value;
            app.initialeHydroPageSettings.quantityAll = app.QuantityAllButton.Value;
            app.initialeHydroPageSettings.folderLocation = app.FolderLocationField.Value;
            app.initialeHydroPageSettings.xmin = app.XminField.Value;
            app.initialeHydroPageSettings.ymin = app.YminField.Value;
            app.initialeHydroPageSettings.xmax = app.XmaxField.Value;
            app.initialeHydroPageSettings.ymax = app.YmaxField.Value;
            app.initialeHydroPageSettings.riverCodeEnable = app.RiverCodeEditor.Enable;
            app.initialeHydroPageSettings.quantityEnable = app.QuantityEditField.Enable;
            app.initialeHydroPageSettings.downloadEnable = app.DownloadButton.Enable;
            app.initialeHydroPageSettings.fetchEnable = app.FetchButton.Enable;
            app.initialeHydroPageSettings.xlim = app.eHydroMap.XLim;
            app.initialeHydroPageSettings.ylim = app.eHydroMap.YLim;
            app.initialeHydroPageSettings.logContent = app.eHydroLog.HTMLSource;
            app.initialeHydroPageSettings.pauseEnable = app.Pause.Enable;
            app.initialeHydroPageSettings.stopEnable = app.Stop.Enable;
            app.initialeHydroPageSettings.homeEnable = app.backHomeButton1.Enable;
        end

        function restoreeHydroPageSettings(app)
            s = app.initialeHydroPageSettings;
            app.StartDateDatePicker.Value = s.startDate;
            app.EndDateDatePicker.Value = s.endDate;
            app.RiverDropDown.Value = s.riverOption;
            app.RiverCodeEditor.Value = s.riverCode;
            app.QuantityEditField.Value = s.quantity;
            app.QuantityAllButton.Value = s.quantityAll;
            app.FolderLocationField.Value = s.folderLocation;
            app.XminField.Value = s.xmin;
            app.YminField.Value = s.ymin;
            app.XmaxField.Value = s.xmax;
            app.YmaxField.Value = s.ymax;
            app.RiverCodeEditor.Enable = s.riverCodeEnable;
            app.QuantityEditField.Enable = s.quantityEnable;
            app.DownloadButton.Enable = s.downloadEnable;
            app.FetchButton.Enable = s.fetchEnable;
            ax = app.eHydroMap;
            xlim(ax,s.xlim);
            ylim(ax,s.ylim);
            app.eHydroLogContent = getThemeCSS(app,app.themeData.(app.settings.theme));
            app.eHydroLog.HTMLSource = app.eHydroLogContent;
            app.backHomeButton1.Enable = s.homeEnable;
            app.Stop.Enable = s.stopEnable;
            app.Pause.Enable = s.pauseEnable;
        end

        function saveSinglePageSettings(app)
            app.initialSinglePageSettings.lineFileButtonText = app.PleasechooseareferencelineButton.Text;
            app.initialSinglePageSettings.lineFileButtonEnable = app.PleasechooseareferencelineButton.Enable;
            app.initialSinglePageSettings.lineFileDropDown = app.RiverLineDropDown.Value;
            app.initialSinglePageSettings.dataFileButtonText = app.PleasechooseadatafileButton.Text;
            app.initialSinglePageSettings.InputCRSDropDown = app.InputCRSDropDown.Value;
            app.initialSinglePageSettings.InputEPSGEditable = app.EPSGEditField.Editable;
            app.initialSinglePageSettings.InputEPSGValue = app.EPSGEditField.Value;
            app.initialSinglePageSettings.VerticalUnits = app.VerticalUnitsDropDown.Value;
            app.initialSinglePageSettings.VerticalType = app.VerticalTypeDropDown.Value;
            app.initialSinglePageSettings.OffsetDistance = app.OffsetDistanceEditField.Value;
            app.initialSinglePageSettings.OffsetDistanceEnable = app.OffsetDistanceEditField.Enable;
            app.initialSinglePageSettings.OffsetAuto = app.OffsetDistanceAutoButton.Value;
            app.initialSinglePageSettings.SampleSpacing = app.SampleSpacingEditField.Value;
            app.initialSinglePageSettings.SampleSpacingEnable = app.SampleSpacingEditField.Enable;
            app.initialSinglePageSettings.SampleSpaciongAuto = app.SampleSpacingAutoButton.Value;
            app.initialSinglePageSettings.Prominence = app.ProminenceEditField.Value;
            app.initialSinglePageSettings.ProminenceEnable = app.ProminenceEditField.Enable;
            app.initialSinglePageSettings.ProminenceAuto = app.ProminenceAutoButton.Value;
            app.initialSinglePageSettings.Threshold = app.ThresholdHPFEditField.Value;
            app.initialSinglePageSettings.ThresholdEnable = app.ThresholdHPFEditField.Enable;
            app.initialSinglePageSettings.ThresholdAuto = app.ThresholdAutoButton.Value;
            app.initialSinglePageSettings.ComputeEnable = app.ComputeButton.Enable;
            app.initialSinglePageSettings.SaveDirectory = app.FolderLocationField_2.Value;
            app.initialSinglePageSettings.Filename = app.EnterFilenameField.Value;
            app.initialSinglePageSettings.Filename2 = app.EnterFilenameField_2.Value;
            app.initialSinglePageSettings.SaveDataEnable = app.SaveDataButton.Enable;
            app.initialSinglePageSettings.SaveFiguresEnable = app.SaveFiguresButton.Enable;
            app.initialSinglePageSettings.singleLogContent = app.singleLogContent;
            app.initialSinglePageSettings.Data = [];
        end

        function restoreSinglePageSettings(app)
            s = app.initialSinglePageSettings;
            app.PleasechooseareferencelineButton.Text = s.lineFileButtonText;
            app.PleasechooseareferencelineButton.Enable = s.lineFileButtonEnable;
            app.RiverLineDropDown.Value = s.lineFileDropDown;
            app.PleasechooseadatafileButton.Text = s.dataFileButtonText;
            app.InputCRSDropDown.Value = s.InputCRSDropDown;
            app.EPSGEditField.Editable = s.InputEPSGEditable;
            app.EPSGEditField.Value = s.InputEPSGValue;
            app.VerticalUnitsDropDown.Value = s.VerticalUnits;
            app.VerticalTypeDropDown.Value = s.VerticalType;
            app.OffsetDistanceEditField.Value = s.OffsetDistance;
            app.OffsetDistanceEditField.Enable = s.OffsetDistanceEnable;
            app.OffsetDistanceAutoButton.Value = s.OffsetAuto;
            app.SampleSpacingEditField.Value = s.SampleSpacing;
            app.SampleSpacingEditField.Enable = s.SampleSpacingEnable;
            app.SampleSpacingAutoButton.Value = s.SampleSpaciongAuto;
            app.ProminenceEditField.Value = s.Prominence;
            app.ProminenceEditField.Enable = s.ProminenceEnable;
            app.ProminenceAutoButton.Value = s.ProminenceAuto;
            app.ThresholdHPFEditField.Value = s.Threshold;
            app.ThresholdHPFEditField.Enable = s.ThresholdEnable;
            app.ThresholdAutoButton.Value = s.ThresholdAuto;
            app.ComputeButton.Enable = s.ComputeEnable;
            app.FolderLocationField_2.Value = s.SaveDirectory;
            app.EnterFilenameField.Value = s.Filename;
            app.ExtDropDown.Value = app.settings.defaultDataType;
            app.EnterFilenameField_2.Value = s.Filename2;
            app.ExtDropDown_2.Value = app.settings.defaultFigureType;
            app.SaveDataButton.Enable = s.SaveDataEnable;
            app.SaveFiguresButton.Enable = s.SaveFiguresEnable;
            app.singleLogContent = getThemeCSS(app,app.themeData.(app.settings.theme));
            app.singleLog.HTMLSource = app.singleLogContent;
            app.D = s.Data;
        end

        function saveBatchPageSettings(app)
            app.initialBatchPageSettings.lineFileButtonText = app.PleasechooseareferencelineButton_2.Text;
            app.initialBatchPageSettings.lineFileButtonEnable = app.PleasechooseareferencelineButton_2.Enable;
            app.initialBatchPageSettings.lineFileDropDown = app.RiverLineDropDown_2.Value;
            app.initialBatchPageSettings.dataFileButtonText = app.PleasechooseadatafolderButton.Text;
            app.initialBatchPageSettings.InputCRSDropDown = app.InputCRSDropDown_2.Value;
            app.initialBatchPageSettings.InputEPSGEditable = app.EPSGEditField_2.Editable;
            app.initialBatchPageSettings.InputEPSGValue = app.EPSGEditField_2.Value;
            app.initialBatchPageSettings.VerticalUnits = app.VerticalUnitsDropDown_2.Value;
            app.initialBatchPageSettings.VerticalType = app.VerticalTypeDropDown_2.Value;
            app.initialBatchPageSettings.OffsetDistance = app.OffsetDistanceEditField_2.Value;
            app.initialBatchPageSettings.OffsetDistanceEnable = app.OffsetDistanceEditField_2.Enable;
            app.initialBatchPageSettings.OffsetAuto = app.OffsetDistanceAutoButton_2.Value;
            app.initialBatchPageSettings.SampleSpacing = app.SampleSpacingEditField_2.Value;
            app.initialBatchPageSettings.SampleSpacingEnable = app.SampleSpacingEditField_2.Enable;
            app.initialBatchPageSettings.SampleSpaciongAuto = app.SampleSpacingAutoButton_2.Value;
            app.initialBatchPageSettings.Prominence = app.ProminenceEditField_2.Value;
            app.initialBatchPageSettings.ProminenceEnable = app.ProminenceEditField_2.Enable;
            app.initialBatchPageSettings.ProminenceAuto = app.ProminenceAutoButton_2.Value;
            app.initialBatchPageSettings.Threshold = app.ThresholdHPFEditField_2.Value;
            app.initialBatchPageSettings.ThresholdEnable = app.ThresholdHPFEditField_2.Enable;
            app.initialBatchPageSettings.ThresholdAuto = app.ThresholdAutoButton_2.Value;
            app.initialBatchPageSettings.ComputeEnable = app.RunButton.Enable;
            app.initialBatchPageSettings.SaveDirectory = app.FolderLocationField_4.Value;
            app.initialBatchPageSettings.Extension = app.settings.defaultDataType;
            app.initialBatchPageSettings.batchLogContent = app.batchLogContent;
            app.initialBatchPageSettings.loopCancel = app.batchLoopCancel;
            app.initialBatchPageSettings.isParallel = app.batchIsParallel;
            app.initialBatchPageSettings.runFlag = app.batchRunFlag;
            app.initialBatchPageSettings.isReady = app.batchIsReady;
            app.initialBatchPageSettings.buttonGroup = app.ButtonGroup.SelectedObject;
            app.initialBatchPageSettings.batchnames = app.batchFilenames;
        end

        function restoreBatchPageSettings(app)
            s = app.initialBatchPageSettings;
            app.PleasechooseareferencelineButton_2.Text = s.lineFileButtonText;
            app.PleasechooseareferencelineButton_2.Enable = s.lineFileButtonEnable;
            app.RiverLineDropDown_2.Value = s.lineFileDropDown;
            app.PleasechooseadatafolderButton.Text = s.dataFileButtonText;
            app.InputCRSDropDown_2.Value = s.InputCRSDropDown;
            app.EPSGEditField_2.Editable = s.InputEPSGEditable;
            app.EPSGEditField_2.Value = s.InputEPSGValue;
            app.VerticalUnitsDropDown_2.Value = s.VerticalUnits;
            app.VerticalTypeDropDown_2.Value = s.VerticalType;
            app.OffsetDistanceEditField_2.Value = s.OffsetDistance;
            app.OffsetDistanceEditField_2.Enable = s.OffsetDistanceEnable;
            app.OffsetDistanceAutoButton_2.Value = s.OffsetAuto;
            app.SampleSpacingEditField_2.Value = s.SampleSpacing;
            app.SampleSpacingEditField_2.Enable = s.SampleSpacingEnable;
            app.SampleSpacingAutoButton_2.Value = s.SampleSpaciongAuto;
            app.ProminenceEditField_2.Value = s.Prominence;
            app.ProminenceEditField_2.Enable = s.ProminenceEnable;
            app.ProminenceAutoButton_2.Value = s.ProminenceAuto;
            app.ThresholdHPFEditField_2.Value = s.Threshold;
            app.ThresholdHPFEditField_2.Enable = s.ThresholdEnable;
            app.ThresholdAutoButton_2.Value = s.ThresholdAuto;
            app.RunButton.Enable = s.ComputeEnable;
            app.FolderLocationField_4.Value = s.SaveDirectory;
            app.ExtDropDown_4.Value = app.settings.defaultDataType;
            app.batchLogContent = getThemeCSS(app,app.themeData.(app.settings.theme));
            app.batchLog.HTMLSource = app.batchLogContent;
            app.batchLoopCancel = s.loopCancel;
            app.batchIsParallel = s.isParallel;
            app.batchIsReady = s.isReady;
            app.batchRunFlag = s.runFlag;
            app.ButtonGroup.SelectedObject = s.buttonGroup;
            app.batchFilenames = s.batchnames;
        end

        function saveGroupPageSettings(app)
            app.initialGroupPageSettings.filelist = app.filePathGroupList;
            app.initialGroupPageSettings.listBoxContent = app.FileListBox.Items;
            app.initialGroupPageSettings.saveDirectory = app.FolderLocationField_3.Value;
            app.initialGroupPageSettings.filename = app.FilenameEditField.Value;
            app.initialGroupPageSettings.Extension = app.settings.defaultDataType;
            app.initialGroupPageSettings.GroupEnable = app.GroupButton.Enable;
            app.initialGroupPageSettings.groupLogContent = app.groupLogContent;
            app.initialGroupPageSettings.HomeEnable = app.backHomeButton4.Enable;
        end

        function restoreGroupPageSettings(app)
            s = app.initialGroupPageSettings;
            app.backHomeButton4.Enable = s.HomeEnable;
            app.filePathGroupList = s.filelist;
            app.FileListBox.Items = s.listBoxContent;
            app.FolderLocationField_3.Value = s.saveDirectory;
            app.FilenameEditField.Value = s.filename;
            app.ExtDropDown_3.Value = app.settings.defaultDataType;
            app.GroupButton.Enable = s.GroupEnable;
            app.groupLogContent = getThemeCSS(app,app.themeData.(app.settings.theme));
            app.groupLog.HTMLSource = app.groupLogContent;
        end

        function saveSettingsPageSettings(app)
            % Make sure to reset maximum workers
                calculateParpoolWorkers(app);
            % Create a unique settings filename and directory
                name = getenv('COMPUTERNAME');
                filename = name + "settings.mat";
                root = "App Dependancies";
                settingsDirectory = fullfile(root,filename);
            % If it exists load it from memory
                if isfile(settingsDirectory)
                    load(settingsDirectory,"userSettings");
                % Make sure all required fields exist and use default values if not
                    app.settings = mergeSettings(app, app.defaultSettings, userSettings);
            % If it does not exist set and save to memory
                else
                    app.settings = app.defaultSettings;
                    directory = fullfile(app.getDocumentsFolder, 'Bedform Finder');
                    if ~isfolder(directory)
                        mkdir(directory)
                    end
                    app.settings.appdirectory = directory;
                    app.settings.parPoolWorkers = max(1,floor(0.75*app.calculatedMaxParpoolWorkers));
                    userSettings = app.settings;
                    save(settingsDirectory,"userSettings")
                end
        end

        function restoreSettingsPageSettings(app)
            calculateParpoolWorkers(app)
            app.Slider.Limits = [1,app.calculatedMaxParpoolWorkers];
            app.Slider.MajorTicks = 1:app.calculatedMaxParpoolWorkers;
            app.Slider.Value = min(app.settings.parPoolWorkers,app.calculatedMaxParpoolWorkers);
            app.DefaultSaveDirectorySetting.Value = app.settings.appdirectory;
            app.DisableParpoolButton.Value = ~app.settings.useParPool;
            app.DisableSingleAnalysisFigCreationButton.Value = ~app.settings.generateFigures;
            app.DropDown.Value = app.settings.defaultDataType;
            app.DropDown2.Value = app.settings.defaultFigureType;
            app.EditField_2.Value = app.settings.figResolution;
            % Check if light of dark theme
            if app.settings.theme == "Light"
                app.SetDarkThemeButton.Text = "Set Dark Theme";
            else
                app.SetDarkThemeButton.Text = "Set Light Theme";
            end
        end

        function saveSinglePlotPageSettings(app)
            app.initialSinglePlotPageSettings.lengthHeatMapEnable = app.LengthHeatmapButton.Enable;
            app.initialSinglePlotPageSettings.heightHeatMapEnable = app.HeightHeatmapButton.Enable;
            app.initialSinglePlotPageSettings.steepnessHeatMapEnable = app.SteepnessHeatmapButton.Enable;
            app.initialSinglePlotPageSettings.lengthPDFEnable = app.LengthPDFButton.Enable;
            app.initialSinglePlotPageSettings.heightPDFEnable = app.HeightPDFButton.Enable;
            app.initialSinglePlotPageSettings.steepnessPDFEnable = app.SteepnessPDFButton.Enable;
            app.initialSinglePlotPageSettings.peaksOnRasterEnable = app.PeaksonRasterButton.Enable;
            app.initialSinglePlotPageSettings.saveFiguresEnable = app.SaveFiguresButton_2.Enable;
            app.initialSinglePlotPageSettings.backEnable = app.BackButton_3.Enable;
            app.initialSinglePlotPageSettings.groupindexValue = app.GroupIndexEditField.Value;
            app.initialSinglePlotPageSettings.groupindexEnable = app.GroupIndexEditField.Enable;
            app.initialSinglePlotPageSettings.groupindexLimits = app.GroupIndexEditField.Limits;
            app.initialSinglePlotPageSettings.dataFile = app.PleasechooseadatafileButton_2.Text;
            app.initialSinglePlotPageSettings.saveDirectory = app.FolderLocationField_5.Value;
            app.initialSinglePlotPageSettings.filename = app.EnterFilenameField_3.Value;
            app.initialSinglePlotPageSettings.Extension = app.settings.defaultFigureType;
            app.initialSinglePlotPageSettings.D = app.D;
            app.initialSinglePlotPageSettings.profileindexEnable = app.ProfileIndexEditField.Enable;
            app.initialSinglePlotPageSettings.profileindexLimits = app.ProfileIndexEditField.Limits;
            app.initialSinglePlotPageSettings.profileindexValue = app.ProfileIndexEditField.Value;
            app.initialSinglePlotPageSettings.bepEnable = app.BedElevationProfileButton.Enable;
            app.initialSinglePlotPageSettings.rawbathymEnable = app.RawBathymetryButton.Enable;
        end

        function restoreSinglePlotPageSettings(app)
            s = app.initialSinglePlotPageSettings;
            app.LengthHeatmapButton.Enable = s.lengthHeatMapEnable;
            app.HeightHeatmapButton.Enable = s.heightHeatMapEnable;
            app.SteepnessHeatmapButton.Enable = s.steepnessHeatMapEnable;
            app.LengthPDFButton.Enable = s.lengthPDFEnable;
            app.HeightPDFButton.Enable = s.heightPDFEnable;
            app.SteepnessPDFButton.Enable = s.steepnessPDFEnable;
            app.PeaksonRasterButton.Enable = s.peaksOnRasterEnable;
            app.SaveFiguresButton_2.Enable = s.saveFiguresEnable;
            app.BackButton_3.Enable = s.backEnable;
            app.GroupIndexEditField.Value = s.groupindexValue;
            app.GroupIndexEditField.Enable = s.groupindexEnable;
            app.GroupIndexEditField.Limits = s.groupindexLimits;
            app.PleasechooseadatafileButton_2.Text = s.dataFile;
            app.FolderLocationField_5.Value = s.saveDirectory;
            app.EnterFilenameField_3.Value = s.filename;
            app.ExtDropDown_5.Value = app.settings.defaultFigureType;
            app.D = s.D;
            app.ProfileIndexEditField.Enable = s.profileindexEnable;
            app.ProfileIndexEditField.Limits = s.profileindexLimits;
            app.ProfileIndexEditField.Value = s.profileindexValue;
            app.BedElevationProfileButton.Enable = s.bepEnable;
            app.RawBathymetryButton.Enable = s.rawbathymEnable;
        end

        function saveClipDataPageSettings(app)
            app.initialClipDataPageSettings.dataFile = app.PleasechooseadatafileButton_3.Text;
            app.initialClipDataPageSettings.groupIndexValue = app.GroupIndexEditField_2.Value;
            app.initialClipDataPageSettings.groupIndexEnable = app.GroupIndexEditField_2.Enable;
            app.initialClipDataPageSettings.groupIndexLimits = app.GroupIndexEditField_2.Limits;
            app.initialClipDataPageSettings.figureGenerateEnable = app.GeneratePeaksonRasterButton.Enable;
            app.initialClipDataPageSettings.addmaskEnable = app.AddMaskButton.Enable;
            app.initialClipDataPageSettings.clipEnable = app.ClipButton.Enable;
            app.initialClipDataPageSettings.saveEnable = app.SaveButton.Enable;
            app.initialClipDataPageSettings.D = app.D;
            app.initialClipDataPageSettings.masks = app.masks;
            app.initialClipDataPageSettings.datafilebuttonEnable = app.PleasechooseadatafileButton_3.Enable;
        end

        function restoreClipDataPageSettings(app)
            s = app.initialClipDataPageSettings;
            ax = app.PeaksOnRasterForClipping;
            delete(findall(ax, 'Type', 'images.roi.Polygon'));
            cla(ax, 'reset');
            ax.Box = 'on';
            ax.XTick = [];
            ax.YTick = [];
            ax.XLabel.String = '';
            ax.YLabel.String = '';
            % Apply theme
            theme = app.themeData.(app.settings.theme);
            ax.Color = theme.AxesBG;
            ax.XColor = theme.Text;
            ax.YColor = theme.Text;
            ax.ZColor = theme.Text;
            ax.GridColor = theme.Grid;
            app.PleasechooseadatafileButton_3.Text = s.dataFile;
            app.GroupIndexEditField_2.Value = s.groupIndexValue;
            app.GroupIndexEditField_2.Enable = s.groupIndexEnable;
            app.GroupIndexEditField_2.Limits = s.groupIndexLimits;
            app.GeneratePeaksonRasterButton.Enable = s.figureGenerateEnable;
            app.AddMaskButton.Enable = s.addmaskEnable;
            app.ClipButton.Enable = s.clipEnable;
            app.SaveButton.Enable = s.saveEnable;
            app.D = s.D;
            app.masks = s.masks;
            app.PleasechooseadatafileButton_3.Enable = s.datafilebuttonEnable;
        end

        function saveBatchPlotPageSettings(app)
            app.initialBatchPlotPageSettings.heightvslength = app.HeightvsLengthButton.Enable;
            app.initialBatchPlotPageSettings.steepnessrelation = app.SteepnessScaleRelationButton.Enable;
            app.initialBatchPlotPageSettings.heightrelation = app.HeightScaleRelationButton.Enable;
            app.initialBatchPlotPageSettings.lengthrelation = app.LengthScaleRelationButton.Enable;
            app.initialBatchPlotPageSettings.datevsreach = app.ReachLocationvsDateButton.Enable;
            app.initialBatchPlotPageSettings.dropdown = app.DropDown_2.Value;
            app.initialBatchPlotPageSettings.dropdownEnable = app.DropDown_2.Enable;
            app.initialBatchPlotPageSettings.saveFiguresEnable = app.SaveFiguresButton_3.Enable;
            app.initialBatchPlotPageSettings.backEnable = app.BackButton_2.Enable;
            app.initialBatchPlotPageSettings.dataFile = app.PleasechooseadatafileButton_4.Text;
            app.initialBatchPlotPageSettings.saveDirectory = app.FolderLocationField_6.Value;
            app.initialBatchPlotPageSettings.filename = app.EnterFilenameField_4.Value;
            app.initialBatchPlotPageSettings.D = app.D;
        end

        function restoreBatchPlotPageSettings(app)
            s = app.initialBatchPlotPageSettings;
            app.HeightvsLengthButton.Enable = s.heightvslength;
            app.SteepnessScaleRelationButton.Enable = s.steepnessrelation;
            app.HeightScaleRelationButton.Enable = s.heightrelation;
            app.LengthScaleRelationButton.Enable = s.lengthrelation;
            app.ReachLocationvsDateButton.Enable = s.datevsreach;
            app.DropDown_2.Value = s.dropdown;
            app.DropDown_2.Enable = s.dropdownEnable;
            app.SaveFiguresButton_3.Enable = s.saveFiguresEnable;
            app.BackButton_2.Enable = s.backEnable;
            app.PleasechooseadatafileButton_4.Text = s.dataFile;
            app.FolderLocationField_6.Value = s.saveDirectory;
            app.EnterFilenameField_4.Value = s.filename;
            app.ExtDropDown_6.Value = app.settings.defaultFigureType;
            app.D = s.D;
        end

        function saveCreateLinePageSettings(app)
            app.initialCreateLinePageSettings.dataFile = app.PleasechooseadatafileButton_5.Text;
            app.initialCreateLinePageSettings.dataFileEnable = app.PleasechooseadatafileButton_5.Enable;
            app.initialCreateLinePageSettings.generateRasterEnable = app.GenerateRasterButton.Enable;
            app.initialCreateLinePageSettings.drawLineEnable = app.DrawLineButton.Enable;
            app.initialCreateLinePageSettings.savedirectory = app.FolderLocationField_7.Enable;
            app.initialCreateLinePageSettings.savePointsEnable = app.SavePointsButton.Enable;
            app.initialCreateLinePageSettings.backEnable = app.BackButton_4.Enable;
            app.initialCreateLinePageSettings.points = app.points;
        end

        function restoreCreateLinePageSettings(app)
            s = app.initialCreateLinePageSettings;
            ax = app.RawDataRaster;
            delete(findall(ax, 'Type', 'images.roi.Polyline'));
            cla(ax, 'reset');
            ax.Box = 'on';
            ax.XTick = [];
            ax.YTick = [];
            ax.XLabel.String = '';
            ax.YLabel.String = '';
            % Apply theme
            theme = app.themeData.(app.settings.theme);
            ax.Color = theme.AxesBG;
            ax.XColor = theme.Text;
            ax.YColor = theme.Text;
            ax.ZColor = theme.Text;
            ax.GridColor = theme.Grid;
            app.PleasechooseadatafileButton_5.Text = s.dataFile;
            app.PleasechooseadatafileButton_5.Enable = s.dataFileEnable;
            app.GenerateRasterButton.Enable = s.generateRasterEnable;
            app.DrawLineButton.Enable = s.drawLineEnable;
            app.FolderLocationField_7.Enable = s.savedirectory;
            app.SavePointsButton.Enable = s.savePointsEnable;
            app.BackButton_4.Enable = s.backEnable;
            app.points = s.points;
        end

        function [filenames, projections, urls] = clearDuplicateUrls(app, filenames, projections, urls, directory)
            % Get a list of filenames, URLS, and coordinate reference systems from eHydro
                filename = string(filenames) + "_A";
                url = string(urls);
                crs = string(projections);
            % Get a list of all files already in the save directory
                names = string(getFilenames(directory));
                logfile = ".processed";
            % Read in names from log file if it exists in the save directory
                if any(names == logfile)
                    processed = string(readlines(fullfile(directory, logfile)));
            % Otherwise create a new log file
                else
                    fclose(fopen(fullfile(directory, logfile), 'w'));
                    processed = strings(0);
                end
            % Get rid of logfile from filenames
                names(names == logfile) = [];
                [~, names, ~] = fileparts(names);
            % Get all unique names from the combined processed list and what was found in the directory
                allNames = unique([names; processed]);
            % Create a mask to compare current list with the already processed list
                mask = ~ismember(filename, allNames);
            % Remove all files that have already been downloaded
                filename = filename(mask);
                url      = url(mask);
                crs       = crs(mask);
            % Rewrite to the logfile to keep updated
                writelines(allNames, fullfile(directory, logfile));
            % Convert to cell array
                filenames   = cellstr(filename);
                projections = cellstr(crs);
                urls        = cellstr(url);
        end

        function updateGroupListBox(app)
            % Get all names in the grouping list box
                [~, name, ext] = cellfun(@fileparts, app.filePathGroupList, 'UniformOutput', false);
                fileNames = strcat(name, ext);
            % If more than one allow grouping
                if numel(fileNames) > 1
                    app.GroupButton.Enable = "on";
            % Otherwise disable grouping
                else
                    app.GroupButton.Enable = "off";
                end
            % Set the list box names
                app.FileListBox.Items = fileNames;
            % UI update
                drawnow;
        end

        function S = importStructure(app, filePath, requiredfields)
            % Initialize structure
                S = [];
            % Try catch for file reading
                try
                % If filepath does not exist, throw error to be caught and handled
                    if ~isfile(filePath)
                        error('File does not exist: %s', filePath);
                    end
                % Get extension info for reading in file
                    [~, ~, ext] = fileparts(filePath);
                    ext = lower(ext);
                % Try catch for file reading safety
                    try
                        switch ext
                        % Read in as .mat file
                            case '.mat'
                                data = load(filePath);
                                vars = fieldnames(data);
                                S = data.(vars{1});
                        % Read in as .json file
                            case '.json'
                                jsonText = fileread(filePath);
                                S = jsondecode(jsonText);
                        % Read in as .hdf5 or .h5 file
                            case '.hdf5'
                                S = h52struct(filePath);
                            case '.h5'
                                S = h52struct(filePath);
                        % If unknown file type throw error to be caught
                            otherwise
                                error('Invalid file type');
                        end
                % Catch and handle error by returning error message
                    catch ME
                        S = sprintf('Error reading file %s: %s', filePath, ME.message);
                        return
                    end
                % If imported not as structure, quietly return error message
                    if ~isstruct(S)
                        S = sprintf('Error: Imported data is not a struct: %s', filePath);
                        return
                    end
                % Ensure all necessary fields are included
                    missing = setdiff(requiredfields, fieldnames(S));
                    if ~isempty(missing)
                        S = sprintf('Error: Missing required fields in %s: %s', filePath, strjoin(missing, ', '));
                        return
                    end
            % Catch and handle error by returning error message
                catch
                    S = "Error: Could not import structure";
                    return
                end
        end

        function updateSettingsStruct(app)
            p = gcp('nocreate');
            app.settings.appdirectory = app.DefaultSaveDirectorySetting.Value;
            app.settings.useParPool = ~app.DisableParpoolButton.Value;
            app.settings.parPoolWorkers = app.Slider.Value;
            app.settings.defaultDataType = app.DropDown.Value;
            app.settings.defaultFigureType = app.DropDown2.Value;
            app.settings.generateFigures = ~app.DisableSingleAnalysisFigCreationButton.Value;
            app.settings.figResolution = app.EditField_2.Value;
            if ~isempty(p) && app.DisableParpoolButton.Value
                delete(p);
            end
        end

        function checkIfDefaultDirectoryIsEmpty(app)
            % If default directory is empty, notify user and set value automatically
                if isempty(app.DefaultSaveDirectorySetting.Value)
                    msgbox('Warning: Default Directory was found to be empty. It has been set automatically to DOCUMENTS. Please go to settings page to update.');
                    app.DefaultSaveDirectorySetting.Value = fullfile(app.getDocumentsFolder, 'Bedform Finder');
                end
            % Reinitialize settings
                updateSettingsStruct(app);
        end

        function onROIDeleted(app, roi)
            % Get index of mask region of intersection to remove
                idx = [];
                for i = 1:numel(app.masks)
                    if isequal(app.masks{i}, roi)
                        idx = i;
                        break;
                    end
                end
            % Remove masked section
                if ~isempty(idx)
                    app.masks(idx) = [];
                end
        end

        function onLineDelete(app, line)
            app.points = [];
        end

        function finishMask(app, h)
            % Set listener and save object handle
                addlistener(h, 'ObjectBeingDestroyed', @(src,event) onROIDeleted(app, src));
                app.masks{end+1} = h; 
                app.AddMaskButton.Enable = "on";
        end

        function finishLine(app,h)
            % Set listener and save object handle
                addlistener(h, 'ObjectBeingDestroyed', @(src,event) onLineDelete(app, src));
                app.points = h;
                app.SavePointsButton.Enable = "on";
                app.DrawLineButton.Enable = "on";
        end

        function T = reprojectTable(app, T, srcEPSG, dstCRS)
            % Get source coordinate reference system from EPSG
                srcCRS = projcrs(srcEPSG);
            % Read first two columns of the table
                E = T{:,1};
                N = T{:,2};
            % Read in optional third column if present
                hasRM = width(T) >= 3;
                if hasRM
                    RM = T{:,3};
                end
            % Reproject to destination reference system
                [lat, lon] = projinv(srcCRS, E, N);
                [E, N] = projfwd(dstCRS, lat, lon);
            % Add values back to new table
                if hasRM
                    T = table(E, N, RM);
                else
                    T = table(E, N);
                end
        
        end

        function saveThemes(app)
            % Get light theme
            app.themeData.Light = struct( ...
                'FigureBG',    app.BedformFinderUIFigure.Color, ...
                'PanelBG',     app.SinglePlot.BackgroundColor, ...
                'ComponentBG', app.DropDown.BackgroundColor, ...
                'InputBG',     app.GroupIndexEditField.BackgroundColor, ...
                'Text',        app.ExtLabel.FontColor, ...
                'AxesBG',      app.eHydroMap.Color, ...
                'Grid',        app.eHydroMap.GridColor, ...
                'Title',       app.Title.FontColor, ...
                'Map',         [0.30 0.50 0.85], ...
                'HTMLBG',      app.GroupIndexEditField.BackgroundColor);
        
            % Define dark theme
            app.themeData.Dark = struct( ...
                'FigureBG',    [0.10 0.10 0.10], ...
                'PanelBG',     [0.15 0.15 0.15], ...
                'ComponentBG', [0.20 0.20 0.20], ...
                'InputBG',     [0.23 0.23 0.23], ...
                'Text',        [0.90 0.90 0.90], ...
                'AxesBG',      [0.12 0.12 0.12], ...
                'Grid',        [0.40 0.40 0.40], ...
                'Title',       [0.85 0.85 0.85], ...
                'Map',         [0.12 0.12 0.12], ...
                'HTMLBG',      [0.15 0.15 0.15]);
        end

        function applyTheme(app,mode)
            % Get the current theme data
                theme = app.themeData.(mode);
            % Get all objects
                objs = findall(app.BedformFinderUIFigure);
            % Loop through each object
                for i = 1:length(objs)
                    obj = objs(i);
                    props = properties(obj);
                    cls = class(obj);
                % Backgrounds
                    if ismember('BackgroundColor',props)
                        try
                            if contains(cls,'Figure')
                                obj.BackgroundColor = theme.FigureBG;
                            elseif contains(cls,'Panel') || ...
                                   contains(cls,'Tab')
                                obj.BackgroundColor = theme.PanelBG;
                            elseif contains(cls,'EditField') || ...
                                   contains(cls,'NumericEditField') || ...
                                   contains(cls,'DatePicker') || ...
                                   contains(cls,'TextArea') || ...
                                   contains(cls,'DropDown')
                                obj.BackgroundColor = theme.InputBG;
                            else
                                obj.BackgroundColor = theme.ComponentBG;
                            end
                        catch
                        end
                    end
                % Text
                    if ismember('FontColor',props)
                        try
                            obj.FontColor = theme.Text;
                        catch
                        end
                    end
                    if ismember('ForegroundColor',props)
                        try
                            obj.ForegroundColor = theme.Text;
                        catch
                        end
                    end
                % Axes
                    if isa(obj,'matlab.ui.control.UIAxes')
                        try
                            obj.Color = theme.AxesBG;
                            obj.XColor = theme.Text;
                            obj.YColor = theme.Text;
                            obj.ZColor = theme.Text;
                            obj.GridColor = theme.Grid;
                        catch
                        end
                    end
                end
            % Set the title
                app.Title.FontColor = theme.Title;
                app.eHydroMap.Color = theme.Map;
        end

        function out = mergeSettings(app, defaults, loaded)
            % Set the output settings
                out = defaults;
            % Make sure loaded exists and is not empty
                if ~exist('loaded','var') || isempty(loaded)
                    return;
                end
            % Get the loaded fields
                loadedFields = fieldnames(loaded);
            % Overwrite values with loaded field values
                for i = 1:length(loadedFields)
                    f = loadedFields{i};
                    out.(f) = loaded.(f);
                end
        end

        function css = getThemeCSS(app,theme)
            css = "<style>" + ...
            "body { background-color:" + rgb(theme.HTMLBG) + ...
            "; color:" + rgb(theme.Text) + ...
            "; font-family: Arial, sans-serif;" + ...
            "; font-size: 18px;" + ...
            "}" + ...
            "div { color:" + rgb(theme.Text) + "; }" + ...
            "a { color: #4aa3ff; }" + ...
            "</style>";
            % Convert rgb to 0-255 for css using normalized rgb
            function c = rgb(v)
                c = "rgb(" + round(v(1)*255) + "," + round(v(2)*255) + "," + round(v(3)*255) + ")";
            end
        end

        function folder = getUniqueFolder(app, folderFieldContent, SubFolderName, runType, exportType, backButton, saveButton)
            % If the folder location is empty use the default setting
                if isempty(folderFieldContent)
                    root = fullfile(app.settings.appdirectory);
            % Otherwise use the manual entry
                else
                    root = strtrim(char(folderFieldContent));
                end
            % If not a real folder make sure user did not make mistake
                if ~isfolder(root)
                    prompt = sprintf('Folder "%s" does not exist. Create it?', root);
                    choice = questdlg(prompt,'Folder Not Found','Yes','No','No');
                % If they did, do not create the folder
                    if ~strcmp(choice,"Yes")
                        if nargin > 5
                            backButton.Enable = "on";
                            saveButton.Enable = "on";
                        end
                        error("Invalid Directory.")
                    end
                % Otherwise make the folder
                    try
                        mkdir(root)
                    catch
                        if nargin > 5
                            backButton.Enable = "on";
                            saveButton.Enable = "on";
                        end
                        error("Could not create directory.")
                    end
                end
            % Get the full directory with root and subfolder
                figuresRoot = fullfile(root,SubFolderName);
                if ~isfolder(figuresRoot)
                    mkdir(figuresRoot);
                end
            % Get datestring for current run
                dateString = datestr(datetime('today'),'mm_dd_yyyy');
                runNumber = 1;
            % Make sure folder has unique name
                while true
                    runFolderName = sprintf('%s_%s_%s%02d',dateString,runType,exportType,runNumber);
                    candidateDir = fullfile(figuresRoot,runFolderName);
                % If is a unique folder create and exit loop
                    if ~isfolder(candidateDir)
                        mkdir(candidateDir);
                        folder = candidateDir;
                        break;
                    end
                % Increment the counter
                    runNumber = runNumber + 1;
                % Handle infinite loops
                    if runNumber > 99
                        if nargin > 4
                            backButton.Enable = "on";
                            saveButton.Enable = "on";
                        end
                        error("Could not create folder name")
                    end
                end
        end

        function name = getUniqueValidName(app, nameFieldContent, dataFilename)
            % Check if name has content
                if isempty(nameFieldContent)
                    nameFieldContent = dataFilename;
                end
            % Check if name is cell
                if iscell(nameFieldContent)
                    nameFieldContent = strjoin(nameFieldContent,' ');
                end
            % Remove invalid characters
                validName = regexprep(nameFieldContent,'[\\/:*?"<>|]','');
                validName = strrep(validName,' ','_');
                name = strtrim(validName);
            % Make sure name is not empty  
                if isempty(validName)
                    name = dataFilename;
                end
        end

        function writeFigure(app, handle, root, name, extension)
            % Create a unique file for each figure including root, subfolder, and name
                baseFigureName = strtrim(strrep(regexprep(handle.Name,'[\\/:*?"<>|]','')," ","_"));
                file = fullfile(root, string(baseFigureName) + "_" + string(name) + string(extension));
            % Create image resolution string for certain export types
                imageResolution = sprintf('-r%d',app.settings.figResolution);
            % Check for figure type
                switch extension
                    case ".png"
                        print(handle,file,'-dpng',char(imageResolution));
                    case ".jpg"
                        print(handle,file,'-djpeg',char(imageResolution));
                    case ".fig"
                        savefig(handle,file)
                    case ".bmp"
                        F = getframe(handle);
                        imwrite(F.cdata,file);
                    case ".tiff"
                        print(handle,file,'-dtiff',char(imageResolution));
                    case ".pdf"
                        print(handle,file,'-dpdf','-vector')
                    case ".eps"
                        print(handle,file,'-depsc','-vector')
                end
        end

        function setUpApp(app)
            % Set home page first
                setHome(app)
                saveThemes(app);
                saveSettingsPageSettings(app);
            % Load or create settings from file
                restoreSettingsPageSettings(app);
                applyTheme(app,app.settings.theme);
            % Set page styling
                app.Home.Title = '';
                app.Home.BorderType = 'none';
                app.eHydro.Title = '';
                app.eHydro.BorderType = 'none';
                app.Single.Title = '';
                app.Single.BorderType = 'none';
                app.Batch.Title = '';
                app.Batch.BorderType = 'none';
                app.Group.Title = '';
                app.Group.BorderType = 'none';
                app.Analysis.Title = '';
                app.Analysis.BorderType = 'none';
                app.Settings.Title = '';
                app.Settings.BorderType = 'none';
                app.SinglePlot.Title = '';
                app.SinglePlot.BorderType = 'none';
                app.GroupPlot.Title = '';
                app.GroupPlot.BorderType = 'none';
                app.ClipData.Title = '';
                app.ClipData.BorderType = 'none';
            % UI update
                drawnow;
            % Save paths, load map, and save page settings
                addpaths(app);
                loadMap(app);
                saveeHydroPageSettings(app);
                saveSinglePageSettings(app);
                saveBatchPageSettings(app);
                saveGroupPageSettings(app);
                saveSinglePlotPageSettings(app);
                saveClipDataPageSettings(app);
                saveBatchPlotPageSettings(app);
                saveCreateLinePageSettings(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set working directory and initialize variables
                setCWD(app)
                setUpApp(app)
        end

        % Button pushed function: seteHydroButton
        function seteHydroButtonPushed(app, event)
            % Reset the page
                restoreeHydroPageSettings(app)
                checkIfDefaultDirectoryIsEmpty(app)
                drawBoundingBox(app)
                seteHydro(app)
        end

        % Button pushed function: setSingleButton
        function setSingleButtonPushed(app, event)
            % Reset the page
                restoreSinglePageSettings(app)
                checkIfDefaultDirectoryIsEmpty(app)
                setSingle(app)
        end

        % Button pushed function: setBatchButton
        function setBatchButtonPushed(app, event)
            % Reset the page
                restoreBatchPageSettings(app)
                checkIfDefaultDirectoryIsEmpty(app)
                setBatch(app)
            % Batch is certainly not ready yet
                app.batchIsReady = false;
            % Disable user leaving
                app.backHomeButton3.Enable = "off";
                app.batchIsParallel = app.settings.useParPool;
            % If batch parallelizing is selected start up workers
                if app.batchIsParallel
                    updateBatchText(app,"======Starting Up Parallel Batch======");
                % Try catch when creating a pool
                    try
                    % Get the number of workers user has set
                        desiredWorkers = app.settings.parPoolWorkers;
                    % Create a new cluster object
                        cluster = parcluster('local');
                    % Set the cluster workers to the users desired number of workers
                        cluster.NumWorkers = desiredWorkers;
                        saveProfile(cluster);
                        pool = gcp('nocreate');
                    % Ensure desired workers is not greater then allowable limit
                        if desiredWorkers > app.calculatedMaxParpoolWorkers
                            updateBatchText(app,sprintf("Warning: Worker count set higher than maximum allowed. Setting worker count to %d",app.calculatedMaxParpoolWorkers));
                            desiredWorkers = app.calculatedMaxParpoolWorkers;
                            cluster.NumWorkers = desiredWorkers;
                            saveProfile(cluster);
                        end
                    % If not pool exists create one with the altered cluster object
                        if isempty(pool)
                            updateBatchText(app,sprintf("Message: Starting up a new pool with %d workers.",desiredWorkers));
                            parfeval(@() parpool(cluster, desiredWorkers), 0);
                    % If there is a pool make sure it has the proper worker count
                        elseif pool.NumWorkers ~= desiredWorkers
                            updateBatchText(app,sprintf("Message: Restarting pool with %d workers.",desiredWorkers));
                            delete(pool);
                            parfeval(@() parpool(cluster, desiredWorkers), 0);
                    % Otherwise just use the existing pool
                        else
                            updateBatchText(app,sprintf("Message: Pool is already initialized with %d workers.",desiredWorkers));
                        end
               % Catch errors and handle by initilizing without parallel processes
                    catch ME
                        updateBatchText(app,"Error: Could not start Parpool. Using batch without Parpool.");
                    % Clean up
                        app.batchIsParallel = false;
                        pool = gcp('nocreate');
                        if ~isempty(pool)
                            delete(pool)
                        end
                    end
            % User has selected no parallel processes
                else
                    updateBatchText(app,"======Batch Started without Parpool======");
                end
            % Above code will be hung in an empty thread until finished. Once finished set the flags appropriately
                if app.batchRunFlag
                    app.RunButton.Enable = "on";
                    app.batchRunFlag = false;
                end
            % Allow user to leave or begin processing
                app.batchIsReady = true;
                app.backHomeButton3.Enable = "on";
        end

        % Button pushed function: setGroupButton
        function setGroupButtonPushed(app, event)
            % Reset the page
                restoreGroupPageSettings(app)
                checkIfDefaultDirectoryIsEmpty(app)
                setGroup(app)
        end

        % Button pushed function: setAnalysisButton
        function setAnalysisButtonPushed(app, event)
            % Reset the page
                checkIfDefaultDirectoryIsEmpty(app)
                setAnalysis(app)
        end

        % Button pushed function: backHomeButton1, backHomeButton2, 
        % ...and 3 other components
        function backHomeButton1Pushed(app, event)
            % Reset the page
                checkIfDefaultDirectoryIsEmpty(app)
                setHome(app)
        end

        % Value changed function: RiverDropDown
        function RiverDropDownValueChanged(app, event)
            % If a value of other is selcted allow manual input
                if app.RiverDropDown.Value == "Other (CODE)"
                    app.RiverCodeEditor.Enable = "on";
            % Otherwise disable manual input
                else
                    app.RiverCodeEditor.Enable = "off";
                end
        end

        % Value changed function: QuantityAllButton
        function QuantityAllButtonValueChanged(app, event)
            % If quantity all is selected, disable selection of numberic value
                if app.QuantityAllButton.Value
                    app.QuantityEditField.Enable = "off";
            % Otherwise allow selection of numeric value
                else
                    app.QuantityEditField.Enable = "on";
                end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            % Directory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field
                if folderPath ~= 0
                    app.FolderLocationField.Value = folderPath;
            % Otherwise just use the default
                else
                    app.FolderLocationField.Value = "";
                end
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            % Disable user from leaving or interacting
                app.DownloadButton.Enable = "off";
                app.FetchButton.Enable = "off";
                app.backHomeButton1.Enable = "off";
            % Get values from UI component fields
                startDate = app.StartDateDatePicker.Value;
                endDate = app.EndDateDatePicker.Value;
                xmax = app.XmaxField.Value;
                ymax = app.YmaxField.Value;
                xmin = app.XminField.Value;
                ymin = app.YminField.Value;
            % Get river code from editor or blank field
                if app.RiverCodeEditor.Enable == "on"
                    river = app.RiverCodeEditor.Value;
                else
                    river = app.RiverDropDown.Value;
                end
            % Make sure that the fields were not empty and values entered are valid
                if startDate < endDate && xmin < xmax && ymin < ymax && ~isempty(river)
                % Message user and query eHdyro for info
                    updateeHydroText(app,"Message: Fetching data from eHydro API. <br>Please Wait...");
                    [names, projection, url] = fetchInfo(startDate,endDate,[xmin,ymin,xmax,ymax],river);
                % Allow user to continue to download and print message
                    if numel(names) > 0
                        updateeHydroText(app,sprintf("Message: Found %d surveys from river %s.<br> Select download to continue, or fetch again.<br>",numel(names),river))
                        app.DownloadButton.Enable = "on";
                        app.filenames = names;
                        app.projections = projection;
                        app.urls = url;
                % Disable user from download and print error if something went wrong
                    else
                        updateeHydroText(app,sprintf("Error: Found %d surveys from river %s.<br> Please fetch again.<br>",numel(names),river))
                        app.DownloadButton.Enable = "off";
                    end
            % Reset if fields did not contain valid values
                else
                    updateeHydroText(app,"Error: Invalid date, geometry range, or river.<br>")
                    app.DownloadButton.Enable = "off";
                end
            % UI update
                drawnow;
            % Reenable user to leave and interact
                app.FetchButton.Enable = "on";
                app.backHomeButton1.Enable = "on";
        end

        % Value changed function: XminField
        function XminFieldValueChanged(app, event)
            % If value changes update the box
                drawBoundingBox(app)
        end

        % Value changed function: YminField
        function YminFieldValueChanged(app, event)
            % If value changes update the box
                drawBoundingBox(app)
        end

        % Value changed function: XmaxField
        function XmaxFieldValueChanged(app, event)
            % If value changes update the box
                drawBoundingBox(app)
        end

        % Value changed function: YmaxField
        function YmaxFieldValueChanged(app, event)
            % If value changes update the box
                drawBoundingBox(app)
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            % Download loop is running so set the flag
                app.downloadLoopRunning = true;
            % Get the river code value either from dropdown or from edit field
                if app.RiverCodeEditor.Enable == "on"
                    folder = app.RiverCodeEditor.Value;
                else
                    folder = app.RiverDropDown.Value;
                end
            % If the folder location is empty use the default setting
                if isempty(app.FolderLocationField.Value)
                    root = app.settings.appdirectory;
                % Make sure it exists just in case
                    if ~isfolder(root)
                        mkdir(root)
                    end
            % Otherwise use the user input value
                else
                    root = strtrim(char(app.FolderLocationField.Value));
                % If not a real folder make sure user did not make mistake
                    if ~isfolder(root)
                        prompt = sprintf('Folder "%s" does not exist. Create it?', root);
                        choice = questdlg(prompt,'Folder Not Found','Yes','No','No');
                    % If intentional make the folder
                        if strcmp(choice,"Yes")
                            try
                                mkdir(root)
                            catch
                                updateeHydroText(app,"Error: Invalid Directory.")
                                seteHydro(app)
                                restoreLoadPageSettings(app)
                                return
                            end
                    % Otherwise exit
                        else
                            updateeHydroText(app,"Error: Invalid Directory.")
                            seteHydro(app)
                            restoreLoadPageSettings(app)
                            return
                        end
                    end
                end
            % Disable user interaction
                app.DownloadButton.Enable = "off";
                app.backHomeButton1.Enable = "off";
                app.FetchButton.Enable ="off";
            % Allow user to pause and stop process
                app.Pause.Enable = "on";
                app.Stop.Enable = "on";
            % Get the full directory with root and subfolder
                directory = fullfile(root,"ehydro",folder);
            % Make sure the subfolder has been created in the root
                if ~isfolder(directory)
                    mkdir(directory)
                end
            % Get rid of any duplicates from the list
                [app.filenames, app.projections, app.urls] = clearDuplicateUrls(app, app.filenames, app.projections, app.urls, directory);
            % Get the number to download, if all is selected run all available files
                if app.QuantityAllButton.Value
                    number = numel(app.filenames);
                else
                    number = min(numel(app.filenames),app.QuantityEditField.Value);
                end
            % Get the proper set of items from 1 to number
                app.filenames = app.filenames(1:number);
                app.projections = app.projections(1:number);
                app.urls = app.urls(1:number);
            % If there are no files, notify user
                if numel(app.urls) == 0
                    updateeHydroText(app, "<br>Found 0 unique files.<br>")
            % Otherwise begin the download
                else
                    updateeHydroText(app,sprintf("<br>Downloading %d unique files.<br>=======Start of Download=======<br>", numel(app.urls)))
                end
            % Loop through each URL
                for i = 1:length(app.urls)
                % Process UI update before pause loop
                    drawnow;
                % Pause loop. If flag is set this will take over temporarily
                    while ~app.downloadLoopRunning
                    % Save CPU cycles
                        pause(0.1)
                    % UI update within the loop
                        drawnow;
                    % Allow for canceling while paused
                        if app.downloadLoopCancel
                            break;
                        end
                    end
                % Check after loop if canceled
                    if app.downloadLoopCancel
                        break;
                    end
                % Get singular information from cell array
                    url = app.urls{i};
                    crs = app.projections{i};
                    name = app.filenames{i};
                % Add line breaks for message format
                    if i>1
                        updateeHydroText(app,"<br>")
                    end
                % Notify user which URL is being processed currently
                    updateeHydroText(app,sprintf("%d/%d: %s",i,numel(app.urls),name));
                % Download file from URL
                    message = downloadFiles(app,url,directory,crs);
                % Process UI update again
                    drawnow;
                % Check if canceled
                    if app.downloadLoopCancel
                        break;
                    end
                % Notify user with message from download
                    updateeHydroText(app,sprintf("&nbsp;&nbsp;&nbsp;&nbsp;%s",message));
                % If no error occured, add file to the processed list
                    if ~contains(message, "Error")
                        writelines(name, fullfile(directory, ".processed"), "WriteMode", "append");
                    end
                % UI update
                    drawnow;
                % Check if canceled
                    if app.downloadLoopCancel
                        break;
                    end
                end
            % If loop was canceled and broken, show proper message
                if app.downloadLoopCancel
                    updateeHydroText(app,"=====Download Cancelled======<br><br>")
                    app.downloadLoopCancel = false;
            % Otherwise notify user download was finished
                else
                    updateeHydroText(app,"=======End of Download=======<br><br>")
                end
            % Reset the page for a new run
                app.downloadLoopRunning = false;
                app.backHomeButton1.Enable = "on";
                app.FetchButton.Enable = "on";
                app.Pause.Enable = "off";
                app.Stop.Enable = "off";
        end

        % Button pushed function: setSettingsButton
        function setSettingsButtonPushed(app, event)
            % Reset the page
                restoreSettingsPageSettings(app)
                checkIfDefaultDirectoryIsEmpty(app)
                setSettings(app)
        end

        % Value changed function: InputCRSDropDown
        function InputCRSDropDownValueChanged(app, event)
            % Check if value is in manual mode
                value = app.InputCRSDropDown.Value;
                if value == "Other (EPSG)"
                    app.EPSGEditField.Value = [];
                    app.EPSGEditField.Editable = "on";
            % Custom setting just for eHdyro
                elseif value == "Other (downloaded from eHydro)"
                    app.EPSGEditField.Value = 5070;
                    app.EPSGEditField.Editable = "off";
            % Convert dropdown values to corresponding code
                else
                    app.EPSGEditField.Value = getEPSGFromString(value);
                    app.EPSGEditField.Editable = "off";
                end
        end

        % Value changed function: OffsetDistanceAutoButton
        function OffsetDistanceAutoButtonValueChanged(app, event)
            % If auto disable edit field input
                if app.OffsetDistanceAutoButton.Value
                    app.OffsetDistanceEditField.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.OffsetDistanceEditField.Enable = "on";
                end
        end

        % Value changed function: SampleSpacingAutoButton
        function SampleSpacingAutoButtonValueChanged(app, event)
            % If auto disable edit field input
                if app.SampleSpacingAutoButton.Value
                    app.SampleSpacingEditField.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.SampleSpacingEditField.Enable = "on";
                end
        end

        % Value changed function: ProminenceAutoButton
        function ProminenceAutoButtonValueChanged(app, event)
            % If auto disable edit field input
                if app.ProminenceAutoButton.Value
                    app.ProminenceEditField.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.ProminenceEditField.Enable = "on";
                end
        end

        % Value changed function: ThresholdAutoButton
        function ThresholdAutoButtonValueChanged(app, event)
            % If auto disable edit field input
                if app.ThresholdAutoButton.Value
                    app.ThresholdHPFEditField.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.ThresholdHPFEditField.Enable = "on";
                end
        end

        % Button pushed function: PleasechooseadatafileButton
        function PleasechooseadatafileButtonPushed(app, event)
            % Valid file types
                filterSpec = {'*.mat;*.xyz;*.csv;*.txt;*.xls;*.xlsx','MAT, XYZ, CSV, TXT, XLS, and XLSX Files'};
            % File pop up window
                [filename, pathname] = uigetfile(filterSpec, 'Select a data file', app.settings.appdirectory);
            % If user chose a file, change the contents field
                if filename ~= 0
                    app.PleasechooseadatafileButton.Text = fullfile(pathname,filename);
                % If the reference line has been chosen either from a file or dropdown, allow compute
                    if app.PleasechooseareferencelineButton.Text ~= "Please choose a reference line." || app.RiverLineDropDown.Value ~= "Other (File)"
                        app.ComputeButton.Enable = "on";
                    end
            % Otherwise set as a default string and disable compute
                else
                    app.PleasechooseadatafileButton.Text = 'Please choose a data file.';
                    app.ComputeButton.Enable = "off";
                end
        end

        % Value changed function: RiverLineDropDown
        function RiverLineDropDownValueChanged(app, event)
                value = app.RiverLineDropDown.Value;
            % If the user chose other, allow for file selection
                if value == "Other (File)"
                    app.PleasechooseareferencelineButton.Enable = "on";
                    app.ComputeButton.Enable = "off";
            % Otherwise disable file selection for river line and check if a data file is selected
                else
                    app.PleasechooseareferencelineButton.Enable = "off";
                    app.PleasechooseareferencelineButton.Text = 'Please choose a reference line.';
                % If a data file is selected allow for compute
                    if app.PleasechooseadatafileButton.Text ~= "Please choose a data file."
                        app.ComputeButton.Enable = "on";
                    end
                end
        end

        % Button pushed function: PleasechooseareferencelineButton
        function PleasechooseareferencelineButtonPushed(app, event)
            % Valid file types
                filterSpec = {'*.mat;*.xyz;*.csv;*.txt;*.xls;*.xlsx','MAT, XYZ, CSV, TXT, XLS, and XLSX Files'};
            % File pop up window
                [filename, pathname] = uigetfile(filterSpec, 'Select a line file', app.settings.appdirectory);
            % If user chose a file, change the contents field
                if filename ~= 0
                    app.PleasechooseareferencelineButton.Text = fullfile(pathname,filename);
                % If a data file is selected allow for compute
                    if app.PleasechooseadatafileButton.Text ~= "Please choose a data file."
                        app.ComputeButton.Enable = "on";
                    end
            % Otherwise set as default string and disable compute
                else
                    app.PleasechooseareferencelineButton.Text = 'Please choose a reference line.';
                    app.ComputeButton.Enable = "off";
                end
        end

        % Button pushed function: DefaultButton_2
        function DefaultButton_2Pushed(app, event)
            % Directory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field
                if folderPath ~= 0
                    app.FolderLocationField_2.Value = folderPath;
            % Otherwise just use the default
                else
                    app.FolderLocationField_2.Value = "";
                end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % Close all currently open figures
                close all
            % Print to the messages
                updateSingleText(app,"<br>=======Start of Compute=======<br>");
                updateSingleText(app,"Message: Importing Data...");
            % Get the EPSG codes
                dataEPSG = app.EPSGEditField.Value;
                lineEPSG = app.EPSGEditField.Value;
            % If river line was chosen from file, get file location
                if app.PleasechooseareferencelineButton.Text ~= "Please choose a reference line."
                    riverLineLoc = app.PleasechooseareferencelineButton.Text;
            % Otherwise load from dependacies and set the new EPSG
                else
                    riverLineLoc = getRiverLineLoc(app.RiverLineDropDown.Value);
                    lineEPSG = 5070;
                end
            % Get file location for the data
                dataFileLoc = app.PleasechooseadatafileButton.Text;
            % Try catch for data loading safety
                try
                % Get the extension for riverline
                    [~,~,ext] = fileparts(riverLineLoc);
                % If .mat load in as a table
                    if strcmpi(ext,'.mat')
                        riverLine = loadMatAsTable(riverLineLoc);
                % Otherwise read raw columns as a table
                    else
                        riverLine = readtable(riverLineLoc);
                    end
                % Check if has minimum 2 columns of data
                    if width(riverLine) < 2 || ~all(varfun(@isnumeric, riverLine, 'OutputFormat','uniform'))
                    % UI update
                        drawnow
                    % Update window with error
                        updateSingleText(app,"Error: River Line must contain at least 2 numeric columns (E, N, {optional RM}).")
                        updateSingleText(app,"=======End of Compute=======<br><br>");
                    % Reset page if error
                        close all
                        app.SaveDataButton.Enable = "off";
                        app.SaveFiguresButton.Enable = "off";
                        app.D = [];
                        return
                    end
                % Get extnesion for data
                    [~,filename,ext] = fileparts(dataFileLoc);
                % If .mat load as table
                    if strcmpi(ext,'.mat')
                        dataFile = loadMatAsTable(dataFileLoc);
                % Otherwise read raw columns as a table
                    else
                        dataFile = readtable(dataFileLoc);
                    end
                % Check if has minimum 3 columns of data
                    if width(dataFile) < 3 || ~all(varfun(@isnumeric, dataFile, 'OutputFormat','uniform'))
                    % UI update
                        drawnow
                    % Update window with error
                        updateSingleText(app,"Error: Data file must contain at least 3 numeric columns (X,Y,Z).")
                        updateSingleText(app,"=======End of Compute=======<br><br>");
                    % Reset page if error
                        close all
                        app.SaveDataButton.Enable = "off";
                        app.SaveFiguresButton.Enable = "off";
                        app.D = [];
                        return
                    end
            % Handle any file reading errors
                catch
                % UI update
                    drawnow
                % Update window with error
                    updateSingleText(app,"Error: Invalid File.")
                    updateSingleText(app,"=======End of Compute=======<br><br>");
                % Reset page if error
                    close all
                    app.SaveDataButton.Enable = "off";
                    app.SaveFiguresButton.Enable = "off";
                    app.D = [];
                    return
                end
            % Make sure that EPSG has been entered
                if isempty(dataEPSG)
                % UI update
                    drawnow
                % Update window with error
                    updateSingleText(app,"Error: Missing EPSG or coordinate reference system code.")
                    updateSingleText(app,"=======End of Compute=======<br><br>");
                % Reset page if error
                    close all
                    app.SaveDataButton.Enable = "off";
                    app.SaveFiguresButton.Enable = "off";
                    app.D = [];
                    return
                end
            % Pull out raw data and line components for processing
                X = dataFile{:,1};
                Y = dataFile{:,2};
                Z = dataFile{:,3};
                E = riverLine{:,1};
                N = riverLine{:,2};
            % Start reprojection
                updateSingleText(app,"Message: Projecting Points...")
            % Choose an coordtinate reference system
                [dataFileCRS,X,Y] = chooseUTMCRS(dataEPSG,X,Y);
            % Reproject points
                srcCRS = projcrs(lineEPSG);
                [lat, lon] = projinv(srcCRS, E, N);
                [E, N] = projfwd(dataFileCRS,lat,lon);
            % Find the necessary center and radius to encompass all points
                [center, radius] = medianCenterCircle(X,Y);
            % Make sure that line falls in proximity to data
                if ~checkLineProximity(center(1),center(2),E,N,800)
                % UI update
                    drawnow
                % Update window with error
                    updateSingleText(app,"Error: River line is not in proximity to data (>800 meters).")
                    updateSingleText(app,"=======End of Compute=======<br><br>");
                % Reset page if error
                    close all
                    app.SaveDataButton.Enable = "off";
                    app.SaveFiguresButton.Enable = "off";
                    app.D = [];
                    return
                end
            % Make sure all units are in meters
                if app.VerticalUnitsDropDown.Value == "US survey feet"
                    Z = unitConversion(Z,"US survey ft","m");
                end
            % Make sure vertical type is in reference to depths
                if app.VerticalTypeDropDown.Value == "Elevation"
                    Z = -Z;
                end
            % Get offset value or set to 1 by default
                if app.OffsetDistanceAutoButton.Value
                    offset = 1;
                else
                    offset = app.OffsetDistanceEditField.Value;
                end
            % Get sample spacing frequency value or set to 1 by default
                if app.SampleSpacingAutoButton.Value
                    sampleSpacing = 1;
                else
                    sampleSpacing = app.SampleSpacingEditField.Value;
                end
            % Get prominence value or set to nan for calculated value
                if app.ProminenceAutoButton.Value
                    prominence = nan;
                else
                    prominence = app.ProminenceEditField.Value;
                end
            % Get high pass filter threshold or set to 100 by default
                if app.ThresholdAutoButton.Value
                    threshold = 100;
                else
                    threshold = app.ThresholdHPFEditField.Value;
                end
            % Try catch for processing
                try
                % Notify user BEPs are being created
                    updateSingleText(app,"Message: Creating BEPs...")
                % Find the number of copies left and right to fill data footprint
                    [right,left] = getCopyRecommendation(E,N,X,Y,offset);
                % Create the BEPs and get the s-z data for 2D processing
                    BEPs = getInterpolationLines(E,N,center,radius,right,left,sampleSpacing,offset);
                    BEPs = interpolateShape(X,Y,Z,BEPs);
                    BEPs = getLongitudinalProfileData(BEPs);
                % Notify user characteristics are being measured from lines
                    updateSingleText(app,"Message: Analyzing...")
                % Reproject river line for data structure
                    riverLine = reprojectTable(app,riverLine,lineEPSG,dataFileCRS);
                % Get the processed Data structure
                    app.D = getProfileData(string(filename), BEPs, riverLine, threshold, unitConversion(prominence,"m","US survey ft"));
                % Add raw data and coordinate reference system to structure
                    app.D.RawData.X = X;
                    app.D.RawData.Y = Y;
                    app.D.RawData.Z = Z;
                    app.D.Parameters.CoordinateReferenceSystem = dataFileCRS;
            % Catch errors from processing
                catch ME
                % UI update
                    drawnow
                % Update window with error
                    updateSingleText(app,"Error: Could not process data.")
                    updateSingleText(app,"=======End of Compute=======<br><br>");
                % Reset page if error
                    close all
                    app.SaveDataButton.Enable = "off";
                    app.SaveFiguresButton.Enable = "off";
                    app.D = [];
                    return
                end
            % If figure generation is selected in settings, create figures
                if app.settings.generateFigures
                    updateSingleText(app,"Message: Creating figures...")
                    createFigures(app.D)
                    app.SaveFiguresButton.Enable = "on";
                end
            % Allow for data save if successful process
                app.SaveDataButton.Enable = "on";
            % Notify user compute has been completed
                updateSingleText(app,"=======End of Compute=======<br><br>");
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Print to messages
                updateSingleText(app,"<br>=======Start of Save=======<br>");
            % Get data structure for saving
                Data = app.D;
            % Get unique root folder
                try
                    root = getUniqueFolder(app,app.FolderLocationField_2.Value,"runs","single","run");
                catch ME
                    updateSingleText(app,"Error: " + ME.message)
                    updateSingleText(app,"=======End of Save=======<br><br>");
                    return
                end
            % Get filename
                validName = getUniqueValidName(app,app.EnterFilenameField.Value,Data.Filename);
            % Get the full file location with root, subfolder, and name
                file = fullfile(root,string(validName) + string(app.ExtDropDown.Value));
            % Begin data save
                updateSingleText(app,"Message: Saving Data...");
            % Try catch for file saving
                try
                % Check for extension type
                    if app.ExtDropDown.Value == ".json"
                    % Create JSON plain text from structure
                        jsonStr = jsonencode(Data);
                        fid = fopen(file,"w","n","UTF-8");
                    % Error if file handle creation was not successful
                        if fid == -1
                            updateSingleText(app,"Error: Cannot open file.")
                            updateSingleText(app,"=======End of Save=======<br><br>");
                            return
                        end
                    % Cleanup file handles and close file after write
                        cleanupObj = onCleanup(@() fclose(fid));
                        fwrite(fid,jsonStr,"char");
                    elseif app.ExtDropDown.Value == ".hdf5"
                    % Write as hdf5
                        struct2h5(file,Data);
                    else
                    % Save as normal matlab file
                        save(file,"Data")
                    end
            % Catch any file saving errors
                catch
                    updateSingleText(app,"Error: Could not save data.")
                    updateSingleText(app,"=======End of Save=======<br><br>");
                    return
                end
            % Notify user when save is complete
                updateSingleText(app,"=======End of Save=======<br><br>");
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)
            % Print to messages
                updateSingleText(app,"<br>=======Start of Save=======<br>");
            % Get all figure handles for saving
                figs = findall(groot,'Type','figure');
                figs = figs(~strcmp({figs.Name},'Bedform Finder'));
            % Make sure figures still are found
                if isempty(figs)
                    updateSingleText(app,"Error: Figures could not be located.")
                    updateSingleText(app,"=======End of Save=======<br><br>");
                    return
                end
            % Get unique root folder
                try
                    root = getUniqueFolder(app,app.FolderLocationField_2.Value,"figures","single","figure");
                catch ME
                    updateSingleText(app,"Error: " + ME.message)
                    updateSingleText(app,"=======End of Save=======<br><br>");
                    return
                end
            % Get filename
                validName = getUniqueValidName(app,app.EnterFilenameField_2.Value,app.D.Filename);
             % Begin figure save
                updateSingleText(app,"Message: Saving Figures...");
            % Try catch for file export
                try
                % Loop through each figure
                    for i = 1:length(figs)
                        writeFigure(app,figs(i),root,validName,app.ExtDropDown_2.Value)
                    % Notify user which figure is currently saved
                        updateSingleText(app,sprintf("Message: %d/%d exported.", i,length(figs)));
                    end
            % Catch any saving errors
                catch
                    updateSingleText(app,"Error: Could not save all figures.")
                    updateSingleText(app,"=======End of Save=======<br><br>");
                    return
                end
            % Notify user when save is complete
                updateSingleText(app,"=======End of Save=======<br><br>");
        end

        % Button pushed function: AddFilesButton
        function AddFilesButtonPushed(app, event)
            % Valid file types
                filterSpec = {'*.mat;*.json;*.h5;*.hdf5', 'MAT, JSON, and HDF5 Files'};
            % File pop up window
                [filename, pathname] = uigetfile(filterSpec, 'Select files to group', app.settings.appdirectory,'MultiSelect','on');
            % If no file was chosen exit
                if isequal(filename,0)
                    return;
                end
            % Convert to cell array
                if ischar(filename)
                    filename = {filename};
                end
            % Try catch for getting files
                try
                % Ensure only allowable files are in the list
                    validFiles = filename(endsWith(lower(filename),{'.mat','.json','.h5','.hdf5'}));
                % Get the full file path
                    fullPaths = fullfile(pathname, validFiles);
                % Current number of files in the list
                    oldNum = numel(app.filePathGroupList);
                % Make sure that current paths in the list are not duplicates
                    app.filePathGroupList = unique([app.filePathGroupList,fullPaths]);
                % Update the list
                    updateGroupListBox(app)
                catch
                % Notify user of error
                    updateGroupText(app,"Error: Could not add files.");
                    return
                end
            % Notify user files were successfully added
                updateGroupText(app,sprintf("Message: Added %d files to list.",numel(app.filePathGroupList)-oldNum));
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Clear the list box contents
                app.FileListBox.Value = {};
        end

        % Button pushed function: RemoveSelectedButton
        function RemoveSelectedButtonPushed(app, event)
            % Try catch for getting files
                try
                % Get the selected names in the list box (just names no root)
                    selectedNames = app.FileListBox.Value;
                % Get just the name and extensions of files in the list (to match the display)
                    [~, names, exts] = cellfun(@fileparts, app.filePathGroupList, 'UniformOutput', false);
                    fileNamesOnly = strcat(names, exts);
                % Cross reference the two lists
                    toKeep = ~ismember(fileNamesOnly, selectedNames);
                % Keep the files that were not selected
                    app.filePathGroupList = app.filePathGroupList(toKeep);
                % Notify the user of successful completion
                    updateGroupText(app,sprintf("Message: Removed %d files from list.", numel(selectedNames)))
                    updateGroupListBox(app)
            % Silently return
                catch
                    return
                end
        end

        % Button pushed function: DefaultButton_3
        function DefaultButton_3Pushed(app, event)
            % Directory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field
                if folderPath ~= 0
                    app.FolderLocationField_3.Value = folderPath;
            % Otherwise just use the default
                else
                    app.FolderLocationField_3.Value = "";
                end
        end

        % Button pushed function: GroupButton
        function GroupButtonPushed(app, event)
            % Print to messages
                updateGroupText(app,"<br>=======Start of Group=======<br>");
            % Disable user from leaving page
                app.backHomeButton4.Enable = "off";
            % If the folder location is empty use the default setting
                if isempty(app.FolderLocationField_3.Value)
                    root = fullfile(app.settings.appdirectory);
            % Otherwise use the manual entry
                else
                    root = strtrim(char(app.FolderLocationField_3.Value));
                end
            % If not a real folder make sure user did not make mistake
                if ~isfolder(root)
                    prompt = sprintf('Folder "%s" does not exist. Create it?', root);
                    choice = questdlg(prompt,'Folder Not Found','Yes','No','No');
                % If they did, do not create the folder
                    if ~strcmp(choice,"Yes")
                        updateGroupText(app,"=======End of Group=======<br><br>");
                        app.backHomeButton4.Enable = "on";
                        return
                    end
                % Otherwise make the folder
                    try
                        mkdir(root)
                    catch
                        updateGroupText(app,"Error: Invalid Directory.")
                        updateGroupText(app,"=======End of Group=======<br><br>");
                        app.backHomeButton4.Enable = "on";
                        return
                    end
                end
            % Get the full directory with root and subfolder
                groupsRoot = fullfile(root,"groups");
                if ~isfolder(groupsRoot)
                    mkdir(groupsRoot);
                end
            % Get the filename
                validName = getUniqueValidName(app,app.FilenameEditField.Value,datestr(datetime('today'),'mm_dd_yyyy'));
            % Make sure name is unique
                runNumber = 1;
                while true
                    fileName = sprintf('%s_group%02d',validName,runNumber);
                    candidateDir = fullfile(groupsRoot,string(fileName) + string(app.ExtDropDown_3.Value));
                % If is a unique filename exit the loop
                    if ~isfile(candidateDir)
                        break;
                    end
                % Increment the counter
                    runNumber = runNumber + 1;
                end
            % Get the full file directory
                file = fullfile(groupsRoot,string(fileName) + string(app.ExtDropDown_3.Value));
            % Get the file list to group
                tempFileList = app.filePathGroupList;
            % Notify user of operation
                updateGroupText(app,"Message: Importing Files...");
            % Loop through each file
                for i = 1:numel(tempFileList)
                    filename = tempFileList{i};
                % Import the file
                    S = importStructure(app,filename,app.requiredFields);
                % Remove the imported file from the list
                    [~,name,ext] = fileparts(filename);
                    fileToRemove = strcat(name, ext);
                    [~, names, exts] = cellfun(@fileparts, app.filePathGroupList, 'UniformOutput', false);
                    fileNamesOnly = strcat(names, exts);
                    toKeep = ~ismember(fileNamesOnly, fileToRemove);
                % If file imported correctly append to structure
                    if isstruct(S)
                        try
                            if ~exist("Data","var")
                                Data = S;
                            else
                                Data = [Data; S];
                            end
                        % Remove file from list is successfully appended
                            app.filePathGroupList = app.filePathGroupList(toKeep);
                            updateGroupListBox(app)
                            updateGroupText(app,sprintf("Message: %d/%d imported.",i,numel(tempFileList)))
                    % Otherwise if error print error to user
                        catch ME
                            updateGroupText(app,"Error: Could not append file.")
                        % Remove bad file from the list
                            app.filePathGroupList = app.filePathGroupList(toKeep);
                            updateGroupListBox(app)
                        end
                % If S returned as a string, display the error string
                    else
                        updateGroupText(app,S)
                    end
                end
            % Try catch for file saving
                try
                % Check if structure has proper contents
                    if isempty(Data(1).L)
                        updateGroupText(app,"Error: No valid data loaded.");
                        updateGroupText(app,"=======End of Group=======<br><br>");
                        return
                    end
                % Print to screen
                    updateGroupText(app,"Message: Beginning save...");
                % Check for extension type
                    if app.ExtDropDown_3.Value == ".json"
                    % Create JSON plain text from structure
                        jsonStr = jsonencode(Data);
                        fid = fopen(file,"w", "n","UTF-8");
                    % Error if file handle creation was not successful
                        if fid == -1 
                            updateGroupText(app,"Error: Cannot open file.")
                            updateGroupText(app,"=======End of Group=======<br><br>");
                            return
                        end
                    % Cleanup file handles and close file after write
                        cleanupObj = onCleanup(@() fclose(fid));
                        fwrite(fid, jsonStr, "char");
                    elseif app.ExtDropDown_3.Value == ".hdf5"
                    % Write as hdf5
                        struct2h5(file,Data);
                    else
                    % Save as normal matlab file
                        save(file,"Data");
                    end
                catch
                % Catch any file saving errors
                    updateGroupText(app,"Error: Could not save data.")
                    updateGroupText(app,"=======End of Group=======<br><br>");
                    return
                end
            % Notify user when group is complete
                updateGroupText(app,"=======End of Group=======<br><br>");
                app.backHomeButton4.Enable = "on";
        end

        % Close request function: BedformFinderUIFigure
        function BedformFinderUIFigureCloseRequest(app, event)
            % Close any open figures
                close all
            % Update the settings
                updateSettingsStruct(app)
                userSettings = app.settings;
            % Get the settings file name and location
                name = getenv('COMPUTERNAME');
                filename = name + "settings.mat";
                root = "App Dependancies";
                settingsDirectory = fullfile(root,filename);
            % Save the settings
                save(settingsDirectory,"userSettings")
            % Clean up app
                delete(app)
            % Delete any existing parpools
                p = gcp('nocreate');
                if ~isempty(p)
                    delete(p);
                end
        end

        % Button pushed function: backHomeButton7
        function backHomeButton7Pushed(app, event)
            setHome(app)
            drawnow;
            updateSettingsStruct(app)
        end

        % Button pushed function: DefaultButton_4
        function DefaultButton_4Pushed(app, event)
            % Direcory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field 
                if folderPath ~= 0
                    app.DefaultSaveDirectorySetting.Value = folderPath;
            % Otherwise set directory as the documents folder
                else
                    directory = fullfile(app.getDocumentsFolder, 'Bedform Finder');
                    if ~isfolder(directory)
                        mkdir(directory)
                    end
                    app.DefaultSaveDirectorySetting.Value = directory;
                end
            % Add directory to settings as the default
                updateSettingsStruct(app)
        end

        % Value changed function: Pause
        function PauseValueChanged(app, event)
            % If paused set the flag
                if app.Pause.Value
                    app.downloadLoopRunning = false;
                else
                    app.downloadLoopRunning = true;
                end
            % UI update
                drawnow;
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            % Set the cancel flag
                app.downloadLoopCancel = true;
            % Unpause
                app.Pause.Value = 0;
        end

        % Button pushed function: PleasechooseareferencelineButton_2
        function PleasechooseareferencelineButton_2Pushed(app, event)
            % Valid file types
                filterSpec = {'*.mat;*.xyz;*.csv;*.txt;*.xls;*.xlsx','MAT, XYZ, CSV, TXT, XLS, and XLSX Files'};
            % File pop up window
                [filename, pathname] = uigetfile(filterSpec, 'Select a line file', app.settings.appdirectory);
            % If user chose a file, change the contents field
                if ~isequal(filename,0)
                % Set the text content
                    app.PleasechooseareferencelineButton_2.Text = fullfile(pathname,filename);
                % Make sure data is chosen
                    if app.PleasechooseadatafolderButton.Text ~= "Please choose a data folder." && app.PleasechooseadatafolderButton.Text ~= "Please choose data files."
                    % If the parpool is set enable the run button
                        if app.batchIsReady
                            app.RunButton.Enable = "on";
                    % Otherwise set the flag that batch can run
                        else
                            app.batchRunFlag = true;
                        end
                    end
            % If no file was chosen disable run
                else
                    app.PleasechooseareferencelineButton_2.Text = 'Please choose a reference line.';
                    app.RunButton.Enable = "off";
                    app.batchRunFlag = false;
                end
        end

        % Button pushed function: PleasechooseadatafolderButton
        function PleasechooseadatafolderButtonPushed(app, event)
            % Check which type is selected
                selectedButton = app.ButtonGroup.SelectedObject;
            % Clear are current filenames
                app.batchFilenames = [];
            % If files is selcted shoose files
                if selectedButton.Text == "Files"
                % Valid file types
                    filterSpec = {'*.mat;*.xyz;*.csv;*.txt;*.xls;*.xlsx','MAT, XYZ, CSV, TXT, XLS, and XLSX Files'};
                % File pop up window
                    [filename, pathname] = uigetfile(filterSpec, 'Select Data Files', app.settings.appdirectory,'MultiSelect','on');
                % If user chose a file, change the contents field
                    if ~isequal(filename,0)
                        app.PleasechooseadatafolderButton.Text = pathname;
                    % Make sure filenames are cell array
                        if ischar(filename)
                            filename = {filename};
                        end
                    % Filter files by valid extensions
                        validFiles = filename(endsWith(lower(filename),{'.mat','.xyz','.csv','.txt','.xls','.xlsx'}));
                        app.batchFilenames = validFiles;
                    end
            % Otherwise choose a folder
                else
                % Directory pop up window
                    folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
                % If the user chose a folder, change the contents field
                    if folderPath ~= 0
                        app.PleasechooseadatafolderButton.Text = folderPath;
                        filename = getFilenames(folderPath);
                    % Filter files by valid extensions
                        validFiles = filename(endsWith(lower(filename),{'.mat','.xyz','.csv','.txt','.xls','.xlsx'}));
                        app.batchFilenames = validFiles;
                    end
                end
            % Now check if batchFilenames variable contains files
                if ~isempty(app.batchFilenames)
                % If it does make sure a line file is chosen
                    if app.PleasechooseareferencelineButton_2.Text ~= "Please choose a reference line." || app.RiverLineDropDown_2.Value ~= "Other (File)"
                    % If the parpool is set enable the run button
                        if app.batchIsReady
                            app.RunButton.Enable = "on";
                    % Otherwise set the flag that batch can run
                        else
                            app.batchRunFlag = true;
                        end
                % If no reference line is chosen set to false
                    else
                        app.batchRunFlag = false;
                        app.RunButton.Enable = "off";
                    end
            % If no batchFilenames are chosen, disable run
                else
                    app.batchRunFlag = false;
                    app.RunButton.Enable = "off";
                % Set to proper text based on files or folder selection
                    if selectedButton.Text == "Files"
                        app.PleasechooseadatafolderButton.Text = "Please choose data files.";
                    else
                        app.PleasechooseadatafolderButton.Text = "Please choose a data folder.";
                    end
                end
        end

        % Value changed function: RiverLineDropDown_2
        function RiverLineDropDown_2ValueChanged(app, event)
            % Get the dropdown value
                value = app.RiverLineDropDown_2.Value;
            % If set to other enable file selection
                if value == "Other (File)"
                    app.PleasechooseareferencelineButton_2.Enable = "on";
                    app.RunButton.Enable = "off";
            % Otherwise check if data files are selected
                else
                    app.PleasechooseareferencelineButton_2.Enable = "off";
                    app.PleasechooseareferencelineButton_2.Text = 'Please choose a reference line.';
                % If a data file is selected set the batch
                    if app.PleasechooseadatafolderButton.Text ~= "Please choose a data folder." && app.PleasechooseadatafolderButton.Text ~= "Please choose data files."
                    % If the parpool is set enable the run button
                        if app.batchIsReady
                            app.RunButton.Enable = "on";
                    % Otherwise set the flag that batch can run
                        else
                            app.batchRunFlag = true;
                        end
                    end
                end
        end

        % Value changed function: InputCRSDropDown_2
        function InputCRSDropDown_2ValueChanged(app, event)
            % Get the coordinate reference system value
                value = app.InputCRSDropDown_2.Value;
            % If set to other allow manual entry
                if value == "Other (EPSG)"
                    app.EPSGEditField_2.Value = [];
                    app.EPSGEditField_2.Editable = "on";
            % If set to eHydro set to conus albers by default
                elseif value == "Other (downloaded from eHydro)"
                    app.EPSGEditField_2.Value = 5070;
                    app.EPSGEditField_2.Editable = "off";
            % Otherwise get the EPSG from lookup table
                else
                    app.EPSGEditField_2.Value = getEPSGFromString(value);
                    app.EPSGEditField_2.Editable = "off";
                end
        end

        % Value changed function: OffsetDistanceAutoButton_2
        function OffsetDistanceAutoButton_2ValueChanged(app, event)
            % If auto disable edit field input
                if app.OffsetDistanceAutoButton_2.Value
                    app.OffsetDistanceEditField_2.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.OffsetDistanceEditField_2.Enable = "on";
                end
        end

        % Value changed function: SampleSpacingAutoButton_2
        function SampleSpacingAutoButton_2ValueChanged(app, event)
            % If auto disable edit field input
                if app.SampleSpacingAutoButton_2.Value
                    app.SampleSpacingEditField_2.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.SampleSpacingEditField_2.Enable = "on";
                end
        end

        % Value changed function: ProminenceAutoButton_2
        function ProminenceAutoButton_2ValueChanged(app, event)
            % If auto disable edit field input
                if app.ProminenceAutoButton_2.Value
                    app.ProminenceEditField_2.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.ProminenceEditField_2.Enable = "on";
                end
        end

        % Value changed function: ThresholdAutoButton_2
        function ThresholdAutoButton_2ValueChanged(app, event)
            % If auto disable edit field input
                if app.ThresholdAutoButton_2.Value
                    app.ThresholdHPFEditField_2.Enable = "off";
            % Otherwise accept manual edit field input
                else
                    app.ThresholdHPFEditField_2.Enable = "on";
                end
        end

        % Button pushed function: DefaultButton_5
        function DefaultButton_5Pushed(app, event)
            % Directory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field
                if folderPath ~= 0
                    app.FolderLocationField_4.Value = folderPath;
            % Otherwise just use the default
                else
                    app.FolderLocationField_4.Value = "";
                end
        end

        % Button pushed function: StopButton
        function StopButtonPushed2(app, event)
            % Set the stop batch flag
                app.stopBatchFlag = true;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Close all currently open figures
                close all
            % Print to the messages
                updateBatchText(app,"<br>=======Start of Batch=======<br>");
            % Get unique root folder
                try
                    root = getUniqueFolder(app,app.FolderLocationField_4.Value,"runs","batch","run");
                catch ME
                    updateBatchText(app,"Error: " + ME.message)
                    updateBatchText(app,"=======End of Save=======<br><br>");
                    return
                end
            % Get the EPSG codes
                dataEPSG = app.EPSGEditField_2.Value;
                lineEPSG = app.EPSGEditField_2.Value;
            % If river line was chosen from file, get file location
                if app.PleasechooseareferencelineButton_2.Text ~= "Please choose a reference line."
                    riverLineLoc = app.PleasechooseareferencelineButton_2.Text;
            % Otherwise load from dependacies and set the new EPSG
                else
                    riverLineLoc = getRiverLineLoc(app.RiverLineDropDown_2.Value);
                    lineEPSG = 5070;
                end
            % Make sure that EPSG has been entered
                if isempty(dataEPSG)
                    updateBatchText(app,"Error: Missing EPSG or coordinate reference system code.");
                    updateBatchText(app,"=======End of Batch=======<br><br>");
                    return
                end
            % Get the directory and files to process
                dataDirectoryLoc = app.PleasechooseadatafolderButton.Text;
                dataFileNames = app.batchFilenames;
            % Make sure that files have been chosen
                if isempty(dataFileNames)
                    updateBatchText(app,"Error: Missing data files.");
                    updateBatchText(app,"=======End of Batch=======<br><br>");
                    return
                end
            % Get all of the parameters
                params.root = root;
                params.dataDirectoryLoc = dataDirectoryLoc;
                params.riverLineLoc = riverLineLoc;
                params.dataEPSG = dataEPSG;
                params.lineEPSG = lineEPSG;
                params.fileExt = app.ExtDropDown_4.Value;
                params.verticalUnits = app.VerticalUnitsDropDown_2.Value;
                params.verticalType = app.VerticalTypeDropDown_2.Value;
                if app.OffsetDistanceAutoButton_2.Value
                    params.offset = 1;
                else
                    params.offset = app.OffsetDistanceEditField_2.Value;
                end
                if app.SampleSpacingAutoButton_2.Value
                    params.sampleSpacing = 1;
                else
                    params.sampleSpacing = app.SampleSpacingEditField_2.Value;
                end
                if app.ProminenceAutoButton_2.Value
                    params.prominence = nan;
                else
                    params.prominence = app.ProminenceEditField_2.Value;
                end
                if app.ThresholdAutoButton_2.Value
                    params.threshold = 100;
                else
                    params.threshold = app.ThresholdHPFEditField_2.Value;
                end
            % Try catch for file reading
                try
                % Get the river line
                    [~,~,ext] = fileparts(params.riverLineLoc);
                    if strcmpi(ext, '.mat')
                        params.riverLine = loadMatAsTable(params.riverLineLoc);
                    else
                        params.riverLine = readtable(params.riverLineLoc);
                    end
                % Make sure it has two columns and optional third
                    if width(params.riverLine) < 2 || ~all(varfun(@isnumeric, params.riverLine, 'OutputFormat','uniform'))
                        updateBatchText(app,"Error: River Line must contain at least 2 numeric columns (E, N, {optional RM}).")
                        updateBatchText(app,"=======End of Batch=======<br><br>");
                        return
                    end
            % Handle errors
                catch
                    updateBatchText(app,"Error: Invalid river file.");
                    updateBatchText(app,"=======End of Batch=======<br><br>");
                    return
                end
            % Get current pool information
                pool = gcp('nocreate');
            % Set the number of loops
                iterations = numel(dataFileNames);
            % Run in parallel if user chose option and pool exists
                if app.batchIsParallel && ~isempty(pool)
                % Create a queue for message
                    q = parallel.pool.DataQueue;
                % Update messages on separate thread
                    afterEach(q, @(msg) updateBatchText(app, msg));
                % Loop through each set
                    parfor i = 1:iterations
                        message = processBatchFile(params, dataFileNames{i});
                        if isempty(message)
                            message = sprintf("%d/%d: %s",i,iterations,dataFileNames{i});
                        end
                        send(q, message);
                    end
            % Other wise run individually
                else
                % Stop is enabled only for non-parallel
                    app.StopButton.Enable = "on";
                % Loop through each set
                    for i = 1:iterations
                        processBatchFile(params, dataFileNames{i},app);
                        app.updateBatchText(sprintf("%d/%d: %s",i,iterations,dataFileNames{i}))
                        drawnow
                        if app.stopBatchFlag
                            app.stopBatchFlag = false;
                            break
                        end
                    end
                    app.StopButton.Enable = "off";
                end
            % Notify user when complete
                updateBatchText(app,"=======End of Batch=======<br><br>");
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % Get the selected button
                selectedButton = app.ButtonGroup.SelectedObject;
            % Clear the filenames and disable running
                app.batchFilenames = [];
                app.RunButton.Enable = "off";
            % If files selected set the appropriate text
                if selectedButton.Text == "Files"
                    app.PleasechooseadatafolderButton.Text = "Please choose data files.";
            % Same for folder
                else
                    app.PleasechooseadatafolderButton.Text = "Please choose a data folder.";
                end
            % Process UI update
                drawnow;
        end

        % Button pushed function: ClipDataButton
        function ClipDataButtonPushed(app, event)
            checkIfDefaultDirectoryIsEmpty(app)
            restoreClipDataPageSettings(app)
            setClipData(app);
        end

        % Button pushed function: GroupPlottingButton
        function GroupPlottingButtonPushed(app, event)
            checkIfDefaultDirectoryIsEmpty(app)
            restoreBatchPlotPageSettings(app);
            setGroupPlot(app);
        end

        % Button pushed function: SinglePlottingButton
        function SinglePlottingButtonPushed(app, event)
            checkIfDefaultDirectoryIsEmpty(app)
            restoreSinglePlotPageSettings(app);
            setSinglePlot(app);
        end

        % Button pushed function: BackButton, BackButton_2, BackButton_3, 
        % ...and 1 other component
        function BackButtonPushed(app, event)
            % Go back to previous page
                setAnalysis(app);
            % Clear the shared variable
                app.D = [];
            % Close all plots
                close all;
        end

        % Button pushed function: PleasechooseadatafileButton_2
        function PleasechooseadatafileButton_2Pushed(app, event)
            % Valied file types
                filterSpec = {'*.mat;*.json;*.hdf5;*.h5','MAT, JSON, and HDF5 Files'};
            % Data file selection window pop up
                [filename, pathname] = uigetfile(filterSpec, 'Select a data file', app.settings.appdirectory);
            % Continue if files were chosen
                if filename ~= 0
                % Change the text content
                    app.PleasechooseadatafileButton_2.Text = fullfile(pathname,filename);
                % Get the data structure
                    app.D = importStructure(app,app.PleasechooseadatafileButton_2.Text,app.requiredFields);
                % If the structure was actually a string, this means error occured
                    if ~isstruct(app.D)
                    % Clear the variable
                        app.D = [];
                    % Reset the text content
                        app.PleasechooseadatafileButton_2.Text = 'Please choose a data file.';
                    % Disable figure creation
                        app.PeaksonRasterButton.Enable = "off";
                        app.SteepnessHeatmapButton.Enable = "off";
                        app.HeightHeatmapButton.Enable = "off";
                        app.LengthHeatmapButton.Enable = "off";
                        app.SteepnessPDFButton.Enable = "off";
                        app.HeightPDFButton.Enable = "off";
                        app.LengthPDFButton.Enable = "off";
                        app.GroupIndexEditField.Enable = "off";
                        app.BedElevationProfileButton.Enable = "off";
                        app.RawBathymetryButton.Enable = "off";
                        app.ProfileIndexEditField.Enable = "off";
                % If is a structure
                    else
                    % Set the limits if a group struct
                        if ~isequal(size(app.D), [1,1])
                            app.GroupIndexEditField.Enable = "on";
                            app.GroupIndexEditField.Limits = [1,numel(app.D)];
                    % Or just disable if a single. No edits necessary
                        else
                            app.GroupIndexEditField.Enable = "off";
                            app.GroupIndexEditField.Limits = [1,Inf];
                            app.GroupIndexEditField.Value = 1;
                        end
                    % Enable figure creation
                        app.PeaksonRasterButton.Enable = "on";
                        app.SteepnessHeatmapButton.Enable = "on";
                        app.HeightHeatmapButton.Enable = "on";
                        app.LengthHeatmapButton.Enable = "on";
                        app.SteepnessPDFButton.Enable = "on";
                        app.HeightPDFButton.Enable = "on";
                        app.LengthPDFButton.Enable = "on";
                        app.BedElevationProfileButton.Enable = "on";
                        app.RawBathymetryButton.Enable = "on";
                        app.ProfileIndexEditField.Enable = "on";
                    % Trip the callback
                        GroupIndexEditFieldValueChanged(app,[])
                    end
            % Otherwise reset
                else
                % Change the text content
                    app.PleasechooseadatafileButton_2.Text = 'Please choose a data file.';
                % Disable figure creation
                    app.PeaksonRasterButton.Enable = "off";
                    app.SteepnessHeatmapButton.Enable = "off";
                    app.HeightHeatmapButton.Enable = "off";
                    app.LengthHeatmapButton.Enable = "off";
                    app.SteepnessPDFButton.Enable = "off";
                    app.HeightPDFButton.Enable = "off";
                    app.LengthPDFButton.Enable = "off";
                    app.BedElevationProfileButton.Enable = "off";
                    app.RawBathymetryButton.Enable = "off";
                    app.GroupIndexEditField.Enable = "off";
                    app.GroupIndexEditField.Limits = [1,Inf];
                    app.GroupIndexEditField.Value = 1;
                    app.ProfileIndexEditField.Enable = "off";
                    app.ProfileIndexEditField.Limits = [1,Inf];
                    app.ProfileIndexEditField.Value = 1;
                end
        end

        % Button pushed function: LengthPDFButton
        function LengthPDFButtonPushed(app, event)
            plotLengthPDF(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: HeightPDFButton
        function HeightPDFButtonPushed(app, event)
            plotHeightPDF(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: SteepnessPDFButton
        function SteepnessPDFButtonPushed(app, event)
            plotSteepnessPDF(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: LengthHeatmapButton
        function LengthHeatmapButtonPushed(app, event)
            plotBedformLengthHeatMap(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: HeightHeatmapButton
        function HeightHeatmapButtonPushed(app, event)
            plotBedformHeightHeatmap(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: SteepnessHeatmapButton
        function SteepnessHeatmapButtonPushed(app, event)
            plotBedformSteepnessHeatmap(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: PeaksonRasterButton
        function PeaksonRasterButtonPushed(app, event)
            plotPeaksOnRaster(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: DefaultButton_6
        function DefaultButton_6Pushed(app, event)
            % Directory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field
                if folderPath ~= 0
                    app.FolderLocationField_5.Value = folderPath;
            % Otherwise just use the default
                else
                    app.FolderLocationField_5.Value = "";
                end
        end

        % Button pushed function: SaveFiguresButton_2
        function SaveFiguresButton_2Pushed(app, event)
        % Get all figure handles for saving
            figs = findall(groot,'Type','figure');
            figs = figs(~strcmp({figs.Name},'Bedform Finder'));
        % Disallow user to click save again or to leave page
            app.BackButton_3.Enable = "off";
            app.SaveFiguresButton_2.Enable = "off";
        % Make sure figures still are found
            if isempty(figs)
                msgbox('Warning: No figures located.');
                app.BackButton_3.Enable = "on";
                app.SaveFiguresButton_2.Enable = "on";
                return
            end
        % Get unique root folder
            try
                root = getUniqueFolder(app,app.FolderLocationField_5.Value,"figures","single","figure",app.BackButton_3,app.SaveFiguresButton_2);
            catch ME
                msgbox(char("Warning: " + ME.message));
                return
            end
        % Get Filename
            validName = getUniqueValidName(app,app.EnterFilenameField_3.Value,app.D(app.GroupIndexEditField.Value).Filename);
        % Try catch for file saving
            try
            % Loop through each figure
                for i = 1:length(figs)
                    writeFigure(app,figs(i),root,validName,app.ExtDropDown_5.Value)
                end
        % Catch any file saving errors
            catch
                msgbox('Error: Could not save figures.');
                app.BackButton_3.Enable = "on";
                app.SaveFiguresButton_2.Enable = "on";
                return
            end
        % Notify user when save is complete
            app.BackButton_3.Enable = "on";
            app.SaveFiguresButton_2.Enable = "on";
        end

        % Button pushed function: PleasechooseadatafileButton_3
        function PleasechooseadatafileButton_3Pushed(app, event)
            % Get the axis handle
                ax = app.PeaksOnRasterForClipping;
            % Remove all current polygons if any persisted
                delete(findall(ax, 'Type', 'images.roi.Polygon'));
            % Valid file types
                filterSpec = {'*.mat;*.json;*.hdf5;*.h5','MAT, JSON, and HDF5 Files'};
            % File selection window pop up
                [filename, pathname] = uigetfile(filterSpec, 'Select a data file', app.settings.appdirectory);
            % Continue if files picked
                if filename ~= 0
                % Set the text content
                    app.PleasechooseadatafileButton_3.Text = fullfile(pathname,filename);
                % UI update
                    drawnow;
                % Get the data structure
                    app.D = importStructure(app,app.PleasechooseadatafileButton_3.Text,app.requiredFields);
                % If string returned error occured
                    if ~isstruct(app.D)
                    % Reset the structure
                        app.D = [];
                    % Change text content
                        app.PleasechooseadatafileButton_3.Text = 'Please choose a data file.';
                    % Reset the page
                        restoreClipDataPageSettings(app)
                % Otherwise continue
                    else
                    % If not 
                        if ~isequal(size(app.D), [1,1])
                            app.GroupIndexEditField_2.Enable = "on";
                            app.GroupIndexEditField_2.Limits = [1,numel(app.D)];
                        else
                            app.GroupIndexEditField_2.Enable = "off";
                            app.GroupIndexEditField_2.Limits = [1,Inf];
                            app.GroupIndexEditField_2.Value = 1;
                        end
    
                        app.GeneratePeaksonRasterButton.Enable = "on";
                    end
            % Otherwise reset
                else
                % Edit text content
                    app.PleasechooseadatafileButton_3.Text = 'Please choose a data file.';
                % Reset the page
                    restoreClipDataPageSettings(app)
                end
        end

        % Button pushed function: GeneratePeaksonRasterButton
        function GeneratePeaksonRasterButtonPushed(app, event)
            % Get the UI Axis handle
                ax = app.PeaksOnRasterForClipping;
            % Allow save
                app.SaveButton.Enable = "on";
            % Plot peaks to the UI Axis handle
                plotPeaksOnRaster(app.D(app.GroupIndexEditField_2.Value), ax)
            % Allow masking
                app.AddMaskButton.Enable = "on";
            % Disallow new file selection
                app.PleasechooseadatafileButton_3.Enable = "off";
            % Disallow index changes
                app.GroupIndexEditField_2.Enable = "off";
            % Clear the masks
                app.masks = [];
        end

        % Button pushed function: AddMaskButton
        function AddMaskButtonPushed(app, event)
            % Disallow adding a mask before completion
                app.AddMaskButton.Enable = "off";
            % Start the draw polygon routine
                h = drawpolygon(app.PeaksOnRasterForClipping);
            % Call the finish mask function
                finishMask(app,h)
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            % Clear the page
                restoreClipDataPageSettings(app)
        end

        % Button pushed function: ClipButton
        function ClipButtonPushed(app, event)
            % Get all the mask regions
                regions = app.masks;
            % Try catch for clipping saftey
                try
                % If there are regions in the mask
                    if ~isempty(regions)
                    % Disallow creating new masks
                        app.AddMaskButton.Enable = "off";
                    % Mask the data with the regions
                        app.D(app.GroupIndexEditField_2.Value) = maskRegions(app.D(app.GroupIndexEditField_2.Value),regions);
                    % Get the UI Axis Handle
                        ax = app.PeaksOnRasterForClipping;
                    % Delete all the polygons drawn
                        delete(findall(ax, 'Type', 'images.roi.Polygon'));
                    % Clear the mask
                        app.masks = [];
                    % Plot the clipped set of data
                        plotPeaksOnRaster(app.D(app.GroupIndexEditField_2.Value), ax)
                    % Allow more masks to be drawn
                        app.AddMaskButton.Enable = "on";
                    end
                catch ME
                % Notify user
                    msgbox('Error: Could not clip data.');
                % Get the UI Axis handle
                    ax = app.PeaksOnRasterForClipping;
                % Clear the polygons
                    delete(findall(ax, 'Type', 'images.roi.Polygon'));
                % Clear the masks
                    app.masks = [];
                % Allow more masks to be drawn
                    app.AddMaskButton.Enable = "on";
                end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Get the data structure from memory
                Data = app.D(app.GroupIndexEditField_2.Value);
            % Get the filename and extension
                path = app.PleasechooseadatafileButton_3.Text;
                [~, ~, ext] = fileparts(path);
                name = Data.Filename;
            % Set the default directory
                directory = app.settings.appdirectory;
                root = fullfile(directory, "clips");
                if ~isfolder(root)
                    mkdir(root);
                end
            % Counter
                runNumber = 1;
            % Loop to create unique name
                while true
                % Create tester name
                    if runNumber > 1
                        clipFileName = sprintf('%s%02d%s',name,runNumber,ext);
                    else
                        clipFileName = string(name) + ext;
                    end
                % Use name for test directory
                    candidateDir = fullfile(root,clipFileName);
                % If unique break loop
                    if ~isfile(candidateDir)
                        break;
                    end
                % Increment counter
                    runNumber = runNumber + 1;
                end
            % Try catch for file reading
                try
                % Check for extension type
                    if ext == ".json"
                    % Create JSON plain text from structure
                        jsonStr = jsonencode(Data);
                        fid = fopen(candidateDir,"w","n","UTF-8");
                    % Error if file handle creation was not successful
                        if fid == -1
                            error("Could not save data.")
                        end
                    % Cleanup file handles and close file after write
                        cleanupObj = onCleanup(@() fclose(fid));
                        fwrite(fid,jsonStr,"char");
                    elseif ext == ".hdf5" || ext == ".h5"
                    % Write as hdf5
                        struct2h5(candidateDir,Data);
                    else
                    % Save as normal matlab file
                        save(candidateDir,"Data")
                    end
                % Notify user when save is complete
                    msgbox('Message: Data saved.');
            % Catch any file saving errors
                catch
                    msgbox('Warning: Could not save clipped data.');
                end
        end

        % Button pushed function: PleasechooseadatafileButton_4
        function PleasechooseadatafileButton_4Pushed(app, event)
            % Valid file types
                filterSpec = {'*.mat;*.json;*.hdf5;*.h5','MAT, JSON, and HDF5 Files'};
            % File selection pop up window
                [filename, pathname] = uigetfile(filterSpec, 'Select a data file', app.settings.appdirectory);
            % Continue if files were selected
                if filename ~= 0
                % Set the text content
                    app.PleasechooseadatafileButton_4.Text = fullfile(pathname,filename);
                % UI update
                    drawnow;
                % Get the data structure
                    app.D = importStructure(app,app.PleasechooseadatafileButton_4.Text,app.requiredFields);
                % If string was returned, error occured
                    if ~isstruct(app.D)
                    % Reset Structure
                        app.D = [];
                    % Reset text content
                        app.PleasechooseadatafileButton_4.Text = 'Please choose a data file.';
                    % Disable figure creation
                        app.HeightvsLengthButton.Enable = "off";
                        app.SteepnessScaleRelationButton.Enable = "off";
                        app.HeightScaleRelationButton.Enable = "off";
                        app.LengthScaleRelationButton.Enable = "off";
                        app.ReachLocationvsDateButton.Enable = "off";
                        app.DropDown_2.Enable = "off";
                % Otherwise allow plotting
                    else
                    % Enable plots
                        app.HeightvsLengthButton.Enable = "on";
                        app.SteepnessScaleRelationButton.Enable = "on";
                        app.HeightScaleRelationButton.Enable = "on";
                        app.LengthScaleRelationButton.Enable = "on";
                        app.ReachLocationvsDateButton.Enable = "on";
                        app.DropDown_2.Enable = "on";
                    end
            % Otherwise reset
                else
                % Reset text content
                    app.PleasechooseadatafileButton_4.Text = 'Please choose a data file.';
                % Disable figure creation
                    app.HeightvsLengthButton.Enable = "off";
                    app.SteepnessScaleRelationButton.Enable = "off";
                    app.HeightScaleRelationButton.Enable = "off";
                    app.LengthScaleRelationButton.Enable = "off";
                    app.ReachLocationvsDateButton.Enable = "off";
                    app.DropDown_2.Enable = "off";
                end
        end

        % Button pushed function: ReachLocationvsDateButton
        function ReachLocationvsDateButtonPushed(app, event)
            plotDatevsReachLoc(app.D,app.DropDown_2.Value)
        end

        % Button pushed function: LengthScaleRelationButton
        function LengthScaleRelationButtonPushed(app, event)
            plotLengthRelation(app.D)
        end

        % Button pushed function: HeightScaleRelationButton
        function HeightScaleRelationButtonPushed(app, event)
            plotHeightRelation(app.D)
        end

        % Button pushed function: SteepnessScaleRelationButton
        function SteepnessScaleRelationButtonPushed(app, event)
            plotSteepnessRelation(app.D)
        end

        % Button pushed function: HeightvsLengthButton
        function HeightvsLengthButtonPushed(app, event)
            plotHeightvsLength(app.D)
        end

        % Button pushed function: DefaultButton_7
        function DefaultButton_7Pushed(app, event)
            % Directory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field
                if folderPath ~= 0
                    app.FolderLocationField_6.Value = folderPath;
            % Otherwise just use the default
                else
                    app.FolderLocationField_6.Value = "";
                end
        end

        % Button pushed function: SaveFiguresButton_3
        function SaveFiguresButton_3Pushed(app, event)
            % Get all figure handles for saving
                figs = findall(groot,'Type','figure');
                figs = figs(~strcmp({figs.Name},'Bedform Finder'));
            % Disallow user to click save again or to leave page
                app.BackButton_2.Enable = "off";
                app.SaveFiguresButton_3.Enable = "off";
            % Make sure figures still are found
                if isempty(figs)
                    msgbox('Warning: No figures located.');
                    app.BackButton_2.Enable = "on";
                    app.SaveFiguresButton_3.Enable = "on";
                    return
                end
            % Get unique root folder
                try
                    root = getUniqueFolder(app,app.FolderLocationField_6.Value,"figures","group","figure",app.BackButton_2,app.SaveFiguresButton_3);
                catch ME
                    msgbox(char("Warning: " + ME.message));
                    return
                end
            % Get Filename
                validName = getUniqueValidName(app,app.EnterFilenameField_4.Value,"DataGroup");
            % Try catch for file saving
                try
                    for i = 1:length(figs)
                        writeFigure(app,figs(i),root,validName,app.ExtDropDown_6.Value)
                    end
            % Catch any file saving errors
                catch
                    msgbox('Error: Could not save figures.');
                    app.BackButton_2.Enable = "on";
                    app.SaveFiguresButton_3.Enable = "on";
                    return
                end
            % Let user leave or save again when complete
                app.BackButton_2.Enable = "on";
                app.SaveFiguresButton_3.Enable = "on";
        end

        % Button pushed function: BedElevationProfileButton
        function BedElevationProfileButtonPushed(app, event)
            plotBedElevationProfile(app.D(app.GroupIndexEditField.Value),app.ProfileIndexEditField.Value)
        end

        % Value changed function: GroupIndexEditField
        function GroupIndexEditFieldValueChanged(app, event)
            % Get the number of lines in the current chosen dataset
                n = numel(app.D(app.GroupIndexEditField.Value).ProfileL);
            % Set the index limits
                app.ProfileIndexEditField.Limits = [1,n];
            % Set the initial value to 1
                app.ProfileIndexEditField.Value = 1;
        end

        % Button pushed function: RawBathymetryButton
        function RawBathymetryButtonPushed(app, event)
            plotRawBathymetry(app.D(app.GroupIndexEditField.Value))
        end

        % Button pushed function: SetDarkThemeButton
        function SetDarkThemeButtonPushed(app, event)
            % If light set to dark
                if app.settings.theme == "Light"
                    app.SetDarkThemeButton.Text = "Set Light Theme";
                    app.settings.theme = "Dark";
            % Otherwise set to light
                else
                    app.SetDarkThemeButton.Text = "Set Dark Theme";
                    app.settings.theme = "Light";
                end
            % Apply the new theme
                applyTheme(app,app.settings.theme)
        end

        % Button pushed function: CreateLineButton
        function CreateLineButtonPushed(app, event)
            checkIfDefaultDirectoryIsEmpty(app)
            restoreCreateLinePageSettings(app)
            setCreateLine(app)
        end

        % Button pushed function: PleasechooseadatafileButton_5
        function PleasechooseadatafileButton_5Pushed(app, event)
            % Valid file types
                filterSpec = {'*.mat;*.xyz;*.csv;*.txt;*.xls;*.xlsx','MAT, XYZ, CSV, TXT, XLS, and XLSX Files'};
            % File pop up window
                [filename, pathname] = uigetfile(filterSpec, 'Select a data file', app.settings.appdirectory);
            % If user chose a file, change the contents field
                if filename ~= 0
                    app.PleasechooseadatafileButton_5.Text = fullfile(pathname,filename);
                    app.GenerateRasterButton.Enable = "on";
            % Otherwise set as a default string and disable compute
                else
                    app.PleasechooseadatafileButton_5.Text = 'Please choose a data file.';
                    app.GenerateRasterButton.Enable = "off";
                end
        end

        % Button pushed function: DefaultButton_8
        function DefaultButton_8Pushed(app, event)
            % Directory pop up window
                folderPath = uigetdir(app.settings.appdirectory, 'Select a Folder');
            % If the user selected a folder add its contents to the field
                if folderPath ~= 0
                    app.FolderLocationField_7.Value = folderPath;
            % Otherwise just use the default
                else
                    app.FolderLocationField_7.Value = "";
                end
        end

        % Button pushed function: ClearPlotButton_2
        function ClearPlotButton_2Pushed(app, event)
            % Clear the page
                restoreCreateLinePageSettings(app)
        end

        % Button pushed function: GenerateRasterButton
        function GenerateRasterButtonPushed(app, event)
            % Get the UI Axis handle
                ax = app.RawDataRaster;
            % Get data
                dataFileLoc = app.PleasechooseadatafileButton_5.Text;
            % Try catch for file reading
                try
                % Get extnesion for data
                    [~,~,ext] = fileparts(dataFileLoc);
                % If .mat load as table
                    if strcmpi(ext,'.mat')
                        dataFile = loadMatAsTable(dataFileLoc);
                % Otherwise read raw columns as a table
                    else
                        dataFile = readtable(dataFileLoc);
                    end
                % Check if has minimum 3 columns of data
                    if width(dataFile) < 3 || ~all(varfun(@isnumeric, dataFile, 'OutputFormat','uniform'))
                    % UI update
                        drawnow
                    % Update window with error
                        msgbox("Error: Data file must contain at least 3 numeric columns (X,Y,Z).")
                        return
                    end
            % Handle any file reading errors
                catch ME
                % UI update
                    drawnow
                % Update window with error
                    msgbox("Error: Invalid File.")
                    return
                end
                X = dataFile{:,1};
                Y = dataFile{:,2};
                Z = dataFile{:,3};
            % Plot peaks to the UI Axis handle
                plotRawBathymetry([], ax, X, Y, Z)
            % Allow masking
                app.DrawLineButton.Enable = "on";
            % Disallow new file selection
                app.PleasechooseadatafileButton_5.Enable = "off";
            % Clear the masks
                app.points = [];
        end

        % Button pushed function: DrawLineButton
        function DrawLineButtonPushed(app, event)
            % Delete all current lines
                app.points = [];
                delete(findall(app.RawDataRaster, 'Type', 'images.roi.Polyline'));
                app.SavePointsButton.Enable = "off";
            % Disallow adding a line before completion
                app.DrawLineButton.Enable = "off";
            % Start the draw polyline routine
                h = drawpolyline(app.RawDataRaster);
            % Call the finish line function
                finishLine(app,h)
        end

        % Button pushed function: SavePointsButton
        function SavePointsButtonPushed(app, event)
            % Stop new saves
                app.SavePointsButton.Enable = "off";
                app.BackButton_4.Enable = "off";
            % Get the line points for saving
                if ~isempty(app.points)
                % Try catch for file saving
                    try
                        pts = app.points.Position;
                        riverLine = table( ...
                        pts(:,1), pts(:,2), ...
                        'VariableNames', {'E','N'});
                    % Get unique root folder
                        try
                            root = getUniqueFolder(app,app.FolderLocationField_7.Value,"lines","single","line",app.BackButton_4,app.SavePointsButton);
                        catch ME
                            msgbox(char("Warning: " + ME.message));
                            return
                        end
                    % Get filename
                        dataFileLoc = app.PleasechooseadatafileButton_5.Text;
                        [~,validName,~] = fileparts(dataFileLoc);
                        directory = fullfile(root,string(validName) + ".xlsx");
                    % Save points
                        writetable(riverLine, directory)
                        msgbox("Message: Points save in: " + directory);

                    catch
                        msgbox("Error: Could not save points.")
                        app.points = [];
                        delete(findall(app.RawDataRaster, 'Type', 'images.roi.Polyline'));
                        app.BackButton_4.Enable = "on";
                    end
                else
                    msgbox("Error: No points found.")
                    app.points = [];
                    delete(findall(app.RawDataRaster, 'Type', 'images.roi.Polyline'));
                    app.BackButton_4.Enable = "on";
                end
            % Allow new saves
                app.points = [];
                delete(findall(app.RawDataRaster, 'Type', 'images.roi.Polyline'));
                app.BackButton_4.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BedformFinderUIFigure = uifigure('Visible', 'off');
            app.BedformFinderUIFigure.Name = 'Bedform Finder';
            app.BedformFinderUIFigure.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'iconLOWRES.png');
            app.BedformFinderUIFigure.CloseRequestFcn = createCallbackFcn(app, @BedformFinderUIFigureCloseRequest, true);
            app.Group = uipanel(app.BedformFinderUIFigure);
            app.groupGrid = uigridlayout(app.Group);
            app.backHomeButton4 = uibutton(app.groupGrid, 'push');
            app.backHomeButton4.ButtonPushedFcn = createCallbackFcn(app, @backHomeButton1Pushed, true);
            app.backHomeButton4.Text = 'Back to Main';
            app.FileListBox = uilistbox(app.groupGrid);
            app.FileListBox.Items = {};
            app.FileListBox.Multiselect = 'on';
            app.FileListBox.Value = {};
            app.RemoveSelectedButton = uibutton(app.groupGrid, 'push');
            app.RemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedButtonPushed, true);
            app.RemoveSelectedButton.Text = 'Remove Selected';
            app.AddFilesButton = uibutton(app.groupGrid, 'push');
            app.AddFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilesButtonPushed, true);
            app.AddFilesButton.Text = 'Add Files';
            app.DefaultButton_3 = uibutton(app.groupGrid, 'push');
            app.DefaultButton_3.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_3Pushed, true);
            app.DefaultButton_3.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton_3.Text = '';
            app.FilenameEditFieldLabel = uilabel(app.groupGrid);
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.groupGrid, 'text');
            app.FilenameEditField.CharacterLimits = [0 64];
            app.FilenameEditField.Placeholder = 'Enter a group filename.';
            app.ExtDropDown_3Label = uilabel(app.groupGrid);
            app.ExtDropDown_3Label.Text = 'Ext:';
            app.ExtDropDown_3 = uidropdown(app.groupGrid);
            app.ExtDropDown_3.Items = {'.mat', '.json', '.hdf5'};
            app.ExtDropDown_3.Value = '.mat';
            app.GroupButton = uibutton(app.groupGrid, 'push');
            app.GroupButton.ButtonPushedFcn = createCallbackFcn(app, @GroupButtonPushed, true);
            app.GroupButton.Enable = 'off';
            app.GroupButton.Text = 'Group';
            app.groupLog = uihtml(app.groupGrid);
            app.groupLog.HTMLSource = ' ';
            app.ClearButton = uibutton(app.groupGrid, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FolderLocationField_3 = uieditfield(app.groupGrid, 'text');
            app.FolderLocationField_3.Placeholder = 'Default';
            app.GroupPlot = uipanel(app.BedformFinderUIFigure);
            app.groupPlotGrid = uigridlayout(app.GroupPlot);
            app.BackButton_2 = uibutton(app.groupPlotGrid, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_2.Text = 'Back';
            app.PleasechooseadatafileButton_4 = uibutton(app.groupPlotGrid, 'push');
            app.PleasechooseadatafileButton_4.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseadatafileButton_4Pushed, true);
            app.PleasechooseadatafileButton_4.Text = 'Please choose a data file.';
            app.SaveFiguresButton_3 = uibutton(app.groupPlotGrid, 'push');
            app.SaveFiguresButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButton_3Pushed, true);
            app.SaveFiguresButton_3.Text = 'Save Figures';
            app.DefaultButton_7 = uibutton(app.groupPlotGrid, 'push');
            app.DefaultButton_7.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_7Pushed, true);
            app.DefaultButton_7.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton_7.Text = '';
            app.ExtLabel_5 = uilabel(app.groupPlotGrid);
            app.ExtLabel_5.Text = 'Ext:';
            app.ExtDropDown_6 = uidropdown(app.groupPlotGrid);
            app.ExtDropDown_6.Items = {'.png', '.jpg', '.fig', '.pdf', '.tiff', '.bmp', '.eps'};
            app.ExtDropDown_6.Value = '.png';
            app.EnterFilenameField_4 = uieditfield(app.groupPlotGrid, 'text');
            app.EnterFilenameField_4.CharacterLimits = [0 64];
            app.EnterFilenameField_4.Placeholder = 'Enter a figure filename.';
            app.FolderLocationField_6 = uieditfield(app.groupPlotGrid, 'text');
            app.FolderLocationField_6.Placeholder = 'Default';
            app.ReachLocationvsDateButton = uibutton(app.groupPlotGrid, 'push');
            app.ReachLocationvsDateButton.ButtonPushedFcn = createCallbackFcn(app, @ReachLocationvsDateButtonPushed, true);
            app.ReachLocationvsDateButton.Enable = 'off';
            app.ReachLocationvsDateButton.Text = 'Reach Location vs Date';
            app.LengthScaleRelationButton = uibutton(app.groupPlotGrid, 'push');
            app.LengthScaleRelationButton.ButtonPushedFcn = createCallbackFcn(app, @LengthScaleRelationButtonPushed, true);
            app.LengthScaleRelationButton.Enable = 'off';
            app.LengthScaleRelationButton.Text = 'Length Scale Relation';
            app.HeightScaleRelationButton = uibutton(app.groupPlotGrid, 'push');
            app.HeightScaleRelationButton.ButtonPushedFcn = createCallbackFcn(app, @HeightScaleRelationButtonPushed, true);
            app.HeightScaleRelationButton.Enable = 'off';
            app.HeightScaleRelationButton.Text = 'Height Scale Relation';
            app.SteepnessScaleRelationButton = uibutton(app.groupPlotGrid, 'push');
            app.SteepnessScaleRelationButton.ButtonPushedFcn = createCallbackFcn(app, @SteepnessScaleRelationButtonPushed, true);
            app.SteepnessScaleRelationButton.Enable = 'off';
            app.SteepnessScaleRelationButton.Text = 'Steepness Scale Relation';
            app.HeightvsLengthButton = uibutton(app.groupPlotGrid, 'push');
            app.HeightvsLengthButton.ButtonPushedFcn = createCallbackFcn(app, @HeightvsLengthButtonPushed, true);
            app.HeightvsLengthButton.Enable = 'off';
            app.HeightvsLengthButton.Text = 'Height vs Length';
            app.DropDown_2 = uidropdown(app.groupPlotGrid);
            app.DropDown_2.Items = {'River Mile', 'River Km'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'River Mile';
            app.SinglePlot = uipanel(app.BedformFinderUIFigure);
            app.singlePlotGrid = uigridlayout(app.SinglePlot);
            app.BackButton_3 = uibutton(app.singlePlotGrid, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_3.Text = 'Back';
            app.GroupIndexLabel = uilabel(app.singlePlotGrid);
            app.GroupIndexLabel.Text = 'Group Index:';
            app.GroupIndexEditField = uieditfield(app.singlePlotGrid, 'numeric');
            app.GroupIndexEditField.Limits = [1 Inf];
            app.GroupIndexEditField.RoundFractionalValues = 'on';
            app.GroupIndexEditField.ValueDisplayFormat = '%d';
            app.GroupIndexEditField.ValueChangedFcn = createCallbackFcn(app, @GroupIndexEditFieldValueChanged, true);
            app.GroupIndexEditField.Enable = 'off';
            app.GroupIndexEditField.Value = 1;
            app.PleasechooseadatafileButton_2 = uibutton(app.singlePlotGrid, 'push');
            app.PleasechooseadatafileButton_2.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseadatafileButton_2Pushed, true);
            app.PleasechooseadatafileButton_2.Text = 'Please choose a data file.';
            app.LengthPDFButton = uibutton(app.singlePlotGrid, 'push');
            app.LengthPDFButton.ButtonPushedFcn = createCallbackFcn(app, @LengthPDFButtonPushed, true);
            app.LengthPDFButton.Enable = 'off';
            app.LengthPDFButton.Text = 'Length PDF';
            app.HeightPDFButton = uibutton(app.singlePlotGrid, 'push');
            app.HeightPDFButton.ButtonPushedFcn = createCallbackFcn(app, @HeightPDFButtonPushed, true);
            app.HeightPDFButton.Enable = 'off';
            app.HeightPDFButton.Text = 'Height PDF';
            app.SteepnessPDFButton = uibutton(app.singlePlotGrid, 'push');
            app.SteepnessPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SteepnessPDFButtonPushed, true);
            app.SteepnessPDFButton.Enable = 'off';
            app.SteepnessPDFButton.Text = 'Steepness PDF';
            app.LengthHeatmapButton = uibutton(app.singlePlotGrid, 'push');
            app.LengthHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @LengthHeatmapButtonPushed, true);
            app.LengthHeatmapButton.Enable = 'off';
            app.LengthHeatmapButton.Text = 'Length Heatmap';
            app.HeightHeatmapButton = uibutton(app.singlePlotGrid, 'push');
            app.HeightHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @HeightHeatmapButtonPushed, true);
            app.HeightHeatmapButton.Enable = 'off';
            app.HeightHeatmapButton.Text = 'Height Heatmap';
            app.SteepnessHeatmapButton = uibutton(app.singlePlotGrid, 'push');
            app.SteepnessHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @SteepnessHeatmapButtonPushed, true);
            app.SteepnessHeatmapButton.Enable = 'off';
            app.SteepnessHeatmapButton.Text = 'Steepness Heatmap';
            app.PeaksonRasterButton = uibutton(app.singlePlotGrid, 'push');
            app.PeaksonRasterButton.ButtonPushedFcn = createCallbackFcn(app, @PeaksonRasterButtonPushed, true);
            app.PeaksonRasterButton.Enable = 'off';
            app.PeaksonRasterButton.Text = 'Peaks on Raster';
            app.SaveFiguresButton_2 = uibutton(app.singlePlotGrid, 'push');
            app.SaveFiguresButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButton_2Pushed, true);
            app.SaveFiguresButton_2.Text = 'Save Figures';
            app.DefaultButton_6 = uibutton(app.singlePlotGrid, 'push');
            app.DefaultButton_6.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_6Pushed, true);
            app.DefaultButton_6.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton_6.Text = '';
            app.ExtLabel_4 = uilabel(app.singlePlotGrid);
            app.ExtLabel_4.Text = 'Ext:';
            app.ExtDropDown_5 = uidropdown(app.singlePlotGrid);
            app.ExtDropDown_5.Items = {'.png', '.jpg', '.fig', '.pdf', '.tiff', '.bmp', '.eps'};
            app.ExtDropDown_5.Value = '.png';
            app.EnterFilenameField_3 = uieditfield(app.singlePlotGrid, 'text');
            app.EnterFilenameField_3.CharacterLimits = [0 64];
            app.EnterFilenameField_3.Placeholder = 'Enter a figure filename.';
            app.FolderLocationField_5 = uieditfield(app.singlePlotGrid, 'text');
            app.FolderLocationField_5.Placeholder = 'Default';
            app.BedElevationProfileButton = uibutton(app.singlePlotGrid, 'push');
            app.BedElevationProfileButton.ButtonPushedFcn = createCallbackFcn(app, @BedElevationProfileButtonPushed, true);
            app.BedElevationProfileButton.Enable = 'off';
            app.BedElevationProfileButton.Text = 'Bed Elevation Profile';
            app.ProfileIndexLabel = uilabel(app.singlePlotGrid);
            app.ProfileIndexLabel.Text = 'Profile Index:';
            app.ProfileIndexEditField = uieditfield(app.singlePlotGrid, 'numeric');
            app.ProfileIndexEditField.Limits = [1 Inf];
            app.ProfileIndexEditField.RoundFractionalValues = 'on';
            app.ProfileIndexEditField.ValueDisplayFormat = '%d';
            app.ProfileIndexEditField.Enable = 'off';
            app.ProfileIndexEditField.Value = 1;
            app.RawBathymetryButton = uibutton(app.singlePlotGrid, 'push');
            app.RawBathymetryButton.ButtonPushedFcn = createCallbackFcn(app, @RawBathymetryButtonPushed, true);
            app.RawBathymetryButton.Enable = 'off';
            app.RawBathymetryButton.Text = 'Raw Bathymetry';
            app.Settings = uipanel(app.BedformFinderUIFigure);
            app.settingsGrid = uigridlayout(app.Settings);
            app.backHomeButton7 = uibutton(app.settingsGrid, 'push');
            app.backHomeButton7.ButtonPushedFcn = createCallbackFcn(app, @backHomeButton7Pushed, true);
            app.backHomeButton7.Text = 'Back to Main';
            app.DefaultButton_4 = uibutton(app.settingsGrid, 'push');
            app.DefaultButton_4.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_4Pushed, true);
            app.DefaultButton_4.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton_4.Text = '';
            app.DefaultSaveDirectoryThisistheDefaultfolderonotherpagesLabel = uilabel(app.settingsGrid);
            app.DefaultSaveDirectoryThisistheDefaultfolderonotherpagesLabel.Text = {'Default Save Directory. '; 'This is the "Default" folder on other pages.'};
            app.DefaultSaveDirectorySetting = uieditfield(app.settingsGrid, 'text');
            app.DefaultSaveDirectorySetting.Editable = 'off';
            app.Slider = uislider(app.settingsGrid);
            app.Slider.Limits = [1 12];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10 10.1 10.2 10.3 10.4 10.5 10.6 10.7 10.8 10.9 11 11.1 11.2 11.3 11.4 11.5 11.6 11.7 11.8 11.9 12];
            app.Slider.Step = 1;
            app.Slider.Value = 8;
            app.ParpoolSettingsParallelProcessesThisdictatesparpoolenablingandworkercountLabel = uilabel(app.settingsGrid);
            app.ParpoolSettingsParallelProcessesThisdictatesparpoolenablingandworkercountLabel.Text = {'Parpool Settings (Parallel Processes).'; 'This dictates parpool enabling and worker count.'};
            app.DropDown = uidropdown(app.settingsGrid);
            app.DropDown.Items = {'.mat', '.json', '.hdf5'};
            app.DropDown.Value = '.mat';
            app.DropDown2 = uidropdown(app.settingsGrid);
            app.DropDown2.Items = {'.png', '.jpg', '.fig', '.pdf', '.tiff', '.bmp', '.eps'};
            app.DropDown2.Value = '.pdf';
            app.DataandFigureExtensionsThischangespreferencesforexportextensionsLabel = uilabel(app.settingsGrid);
            app.DataandFigureExtensionsThischangespreferencesforexportextensionsLabel.Text = {'Data and Figure Extensions.'; 'This changes preferences for export extensions.'};
            app.DisableParpoolButton = uibutton(app.settingsGrid, 'state');
            app.DisableParpoolButton.Text = 'Disable Parpool';
            app.DisableSingleAnalysisFigCreationButton = uibutton(app.settingsGrid, 'state');
            app.DisableSingleAnalysisFigCreationButton.Text = 'Disable Single Analysis Fig Creation';
            app.EditField_2 = uieditfield(app.settingsGrid, 'numeric');
            app.EditField_2.Limits = [10 10000];
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueDisplayFormat = '%d';
            app.EditField_2.Value = 300;
            app.FigureSettingsSingleanalysiscontrolandfigureresolutionDPILabel = uilabel(app.settingsGrid);
            app.FigureSettingsSingleanalysiscontrolandfigureresolutionDPILabel.Text = {'Figure Settings.'; 'Single analysis control and figure resolution (DPI).'};
            app.SetDarkThemeButton = uibutton(app.settingsGrid, 'push');
            app.SetDarkThemeButton.ButtonPushedFcn = createCallbackFcn(app, @SetDarkThemeButtonPushed, true);
            app.SetDarkThemeButton.Text = 'Set Dark Theme';
            app.ThemeSettingsTogglebetweenDarkandLightmodeLabel = uilabel(app.settingsGrid);
            app.ThemeSettingsTogglebetweenDarkandLightmodeLabel.Text = {'Theme Settings.'; 'Toggle between Dark and Light mode.'};
            app.ClipData = uipanel(app.BedformFinderUIFigure);
            app.clipDataGrid = uigridlayout(app.ClipData);
            app.PeaksOnRasterForClipping = uiaxes(app.clipDataGrid);
            app.PeaksOnRasterForClipping.XTick = [];
            app.PeaksOnRasterForClipping.YTick = [];
            app.BackButton = uibutton(app.clipDataGrid, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.GroupIndexLabel_2 = uilabel(app.clipDataGrid);
            app.GroupIndexLabel_2.Text = 'Group Index:';
            app.GroupIndexEditField_2 = uieditfield(app.clipDataGrid, 'numeric');
            app.GroupIndexEditField_2.Limits = [1 Inf];
            app.GroupIndexEditField_2.RoundFractionalValues = 'on';
            app.GroupIndexEditField_2.ValueDisplayFormat = '%d';
            app.GroupIndexEditField_2.Enable = 'off';
            app.GroupIndexEditField_2.Value = 1;
            app.PleasechooseadatafileButton_3 = uibutton(app.clipDataGrid, 'push');
            app.PleasechooseadatafileButton_3.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseadatafileButton_3Pushed, true);
            app.PleasechooseadatafileButton_3.Text = 'Please choose a data file.';
            app.GeneratePeaksonRasterButton = uibutton(app.clipDataGrid, 'push');
            app.GeneratePeaksonRasterButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePeaksonRasterButtonPushed, true);
            app.GeneratePeaksonRasterButton.Enable = 'off';
            app.GeneratePeaksonRasterButton.Text = 'Generate Peaks on Raster';
            app.AddMaskButton = uibutton(app.clipDataGrid, 'push');
            app.AddMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddMaskButtonPushed, true);
            app.AddMaskButton.Enable = 'off';
            app.AddMaskButton.Text = 'Add Mask';
            app.ClipButton = uibutton(app.clipDataGrid, 'push');
            app.ClipButton.ButtonPushedFcn = createCallbackFcn(app, @ClipButtonPushed, true);
            app.ClipButton.Text = 'Clip';
            app.SaveButton = uibutton(app.clipDataGrid, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ClearPlotButton = uibutton(app.clipDataGrid, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.CreateLine = uipanel(app.BedformFinderUIFigure);
            app.createLineGrid = uigridlayout(app.CreateLine);
            app.RawDataRaster = uiaxes(app.createLineGrid);
            app.RawDataRaster.XTick = [];
            app.RawDataRaster.YTick = [];
            app.BackButton_4 = uibutton(app.createLineGrid, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_4.Text = 'Back';
            app.PleasechooseadatafileButton_5 = uibutton(app.createLineGrid, 'push');
            app.PleasechooseadatafileButton_5.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseadatafileButton_5Pushed, true);
            app.PleasechooseadatafileButton_5.Text = 'Please choose a data file.';
            app.GenerateRasterButton = uibutton(app.createLineGrid, 'push');
            app.GenerateRasterButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRasterButtonPushed, true);
            app.GenerateRasterButton.Enable = 'off';
            app.GenerateRasterButton.Text = 'Generate Raster';
            app.DrawLineButton = uibutton(app.createLineGrid, 'push');
            app.DrawLineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLineButtonPushed, true);
            app.DrawLineButton.Enable = 'off';
            app.DrawLineButton.Text = 'Draw Line';
            app.SavePointsButton = uibutton(app.createLineGrid, 'push');
            app.SavePointsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePointsButtonPushed, true);
            app.SavePointsButton.Enable = 'off';
            app.SavePointsButton.Text = 'Save Points';
            app.ClearPlotButton_2 = uibutton(app.createLineGrid, 'push');
            app.ClearPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButton_2Pushed, true);
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.DefaultButton_8 = uibutton(app.createLineGrid, 'push');
            app.DefaultButton_8.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_8Pushed, true);
            app.DefaultButton_8.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton_8.Text = '';
            app.FolderLocationField_7 = uieditfield(app.createLineGrid, 'text');
            app.FolderLocationField_7.Placeholder = 'Default';
            app.SaveDirectoryLabel = uilabel(app.createLineGrid);
            app.SaveDirectoryLabel.Text = 'Save Directory:';
            app.TipDoubleclickrightclickorpressentertofinishdrawinglineLabel = uilabel(app.createLineGrid);
            app.TipDoubleclickrightclickorpressentertofinishdrawinglineLabel.Text = 'Tip: Double click, right click, or press enter to finish drawing line.';
            app.eHydro = uipanel(app.BedformFinderUIFigure);
            app.eHydroGrid = uigridlayout(app.eHydro);
            app.eHydroMap = uiaxes(app.eHydroGrid);
            title(app.eHydroMap, 'Title')
            xlabel(app.eHydroMap, 'X')
            ylabel(app.eHydroMap, 'Y')
            zlabel(app.eHydroMap, 'Z')
            app.backHomeButton1 = uibutton(app.eHydroGrid, 'push');
            app.backHomeButton1.ButtonPushedFcn = createCallbackFcn(app, @backHomeButton1Pushed, true);
            app.backHomeButton1.Text = 'Back to Main';
            app.XminField = uieditfield(app.eHydroGrid, 'numeric');
            app.XminField.Limits = [-125 -60];
            app.XminField.ValueChangedFcn = createCallbackFcn(app, @XminFieldValueChanged, true);
            app.XminField.Value = -95;
            app.YminField = uieditfield(app.eHydroGrid, 'numeric');
            app.YminField.Limits = [20 50];
            app.YminField.ValueChangedFcn = createCallbackFcn(app, @YminFieldValueChanged, true);
            app.YminField.Value = 38;
            app.XmaxField = uieditfield(app.eHydroGrid, 'numeric');
            app.XmaxField.Limits = [-125 -60];
            app.XmaxField.ValueChangedFcn = createCallbackFcn(app, @XmaxFieldValueChanged, true);
            app.XmaxField.Value = -90;
            app.YmaxField = uieditfield(app.eHydroGrid, 'numeric');
            app.YmaxField.Limits = [20 50];
            app.YmaxField.ValueChangedFcn = createCallbackFcn(app, @YmaxFieldValueChanged, true);
            app.YmaxField.Value = 41;
            app.StartDateLabel = uilabel(app.eHydroGrid);
            app.StartDateLabel.Text = 'Start Date:';
            app.StartDateDatePicker = uidatepicker(app.eHydroGrid);
            app.EndDateLabel = uilabel(app.eHydroGrid);
            app.EndDateLabel.Text = 'End Date:';
            app.EndDateDatePicker = uidatepicker(app.eHydroGrid);
            app.DefaultButton = uibutton(app.eHydroGrid, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton.Tooltip = {'Directory Placed In %DOCUMENTS% By Default'};
            app.DefaultButton.Text = '';
            app.FetchButton = uibutton(app.eHydroGrid, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.DownloadButton = uibutton(app.eHydroGrid, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Enable = 'off';
            app.DownloadButton.Text = 'Download';
            app.QuantityLabel = uilabel(app.eHydroGrid);
            app.QuantityLabel.Text = 'Quantity:';
            app.QuantityEditField = uieditfield(app.eHydroGrid, 'numeric');
            app.QuantityEditField.Limits = [1 Inf];
            app.QuantityEditField.RoundFractionalValues = 'on';
            app.QuantityEditField.Enable = 'off';
            app.QuantityEditField.Tooltip = {'Limits Download Quantity To The Amount Specified'};
            app.QuantityEditField.Value = 100;
            app.QuantityAllButton = uibutton(app.eHydroGrid, 'state');
            app.QuantityAllButton.ValueChangedFcn = createCallbackFcn(app, @QuantityAllButtonValueChanged, true);
            app.QuantityAllButton.Tooltip = {'Dowloads All Values Fetched'};
            app.QuantityAllButton.Text = 'ALL';
            app.QuantityAllButton.Value = true;
            app.RiverLabel = uilabel(app.eHydroGrid);
            app.RiverLabel.Text = 'River:';
            app.RiverDropDown = uidropdown(app.eHydroGrid);
            app.RiverDropDown.Items = {'Mississippi (UM)', 'Mississippi (LM)', 'Mississippi (MS)', 'Mississippi (MD)', 'Missouri (MO)', 'Arkansas (AR)', 'Ohio (OH)', 'Illinois (IL)', 'Other (CODE)'};
            app.RiverDropDown.ValueChangedFcn = createCallbackFcn(app, @RiverDropDownValueChanged, true);
            app.RiverDropDown.Tooltip = {'Choose A River'};
            app.RiverDropDown.Value = 'Other (CODE)';
            app.RiverCodeEditor = uieditfield(app.eHydroGrid, 'text');
            app.RiverCodeEditor.Tooltip = {'Visit eHydro For More Examples'};
            app.RiverCodeEditor.Placeholder = 'Ex: TN';
            app.FolderLocationsubfolderswillbecreatedforeachrivercodeLabel = uilabel(app.eHydroGrid);
            app.FolderLocationsubfolderswillbecreatedforeachrivercodeLabel.Text = 'Folder Location: (sub folders will be created for each river code)';
            app.XminLabel = uilabel(app.eHydroGrid);
            app.XminLabel.Text = 'Xmin:';
            app.YminLabel = uilabel(app.eHydroGrid);
            app.YminLabel.Text = 'Ymin:';
            app.XmaxLabel = uilabel(app.eHydroGrid);
            app.XmaxLabel.Text = 'Xmax:';
            app.YmaxLabel = uilabel(app.eHydroGrid);
            app.YmaxLabel.Text = 'Ymax:';
            app.eHydroLog = uihtml(app.eHydroGrid);
            app.eHydroLog.HTMLSource = ' ';
            app.FolderLocationField = uieditfield(app.eHydroGrid, 'text');
            app.FolderLocationField.Placeholder = 'Default';
            app.Pause = uibutton(app.eHydroGrid, 'state');
            app.Pause.ValueChangedFcn = createCallbackFcn(app, @PauseValueChanged, true);
            app.Pause.Enable = 'off';
            app.Pause.Text = 'Pause';
            app.Stop = uibutton(app.eHydroGrid, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Enable = 'off';
            app.Stop.Text = 'Stop';
            app.Home = uipanel(app.BedformFinderUIFigure);
            app.homeGrid = uigridlayout(app.Home);
            app.seteHydroButton = uibutton(app.homeGrid, 'push');
            app.seteHydroButton.ButtonPushedFcn = createCallbackFcn(app, @seteHydroButtonPushed, true);
            app.seteHydroButton.Text = 'eHydro';
            app.setSingleButton = uibutton(app.homeGrid, 'push');
            app.setSingleButton.ButtonPushedFcn = createCallbackFcn(app, @setSingleButtonPushed, true);
            app.setSingleButton.Text = 'Single Analysis';
            app.setBatchButton = uibutton(app.homeGrid, 'push');
            app.setBatchButton.ButtonPushedFcn = createCallbackFcn(app, @setBatchButtonPushed, true);
            app.setBatchButton.Text = 'Batch';
            app.setGroupButton = uibutton(app.homeGrid, 'push');
            app.setGroupButton.ButtonPushedFcn = createCallbackFcn(app, @setGroupButtonPushed, true);
            app.setGroupButton.Text = 'Group Data';
            app.setAnalysisButton = uibutton(app.homeGrid, 'push');
            app.setAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @setAnalysisButtonPushed, true);
            app.setAnalysisButton.Text = 'Data Analysis';
            app.Title = uilabel(app.homeGrid);
            app.Title.Text = 'Bedform Finder';
            app.setSettingsButton = uibutton(app.homeGrid, 'push');
            app.setSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @setSettingsButtonPushed, true);
            app.setSettingsButton.Text = 'Settings';
            app.Image = uiimage(app.homeGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'titlefigure.png');
            app.Analysis = uipanel(app.BedformFinderUIFigure);
            app.analysisGrid = uigridlayout(app.Analysis);
            app.backHomeButton5 = uibutton(app.analysisGrid, 'push');
            app.backHomeButton5.ButtonPushedFcn = createCallbackFcn(app, @backHomeButton1Pushed, true);
            app.backHomeButton5.Text = 'Back to Main';
            app.SinglePlottingButton = uibutton(app.analysisGrid, 'push');
            app.SinglePlottingButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePlottingButtonPushed, true);
            app.SinglePlottingButton.Text = 'Single Plotting';
            app.GroupPlottingButton = uibutton(app.analysisGrid, 'push');
            app.GroupPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @GroupPlottingButtonPushed, true);
            app.GroupPlottingButton.Text = 'Group Plotting';
            app.ClipDataButton = uibutton(app.analysisGrid, 'push');
            app.ClipDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClipDataButtonPushed, true);
            app.ClipDataButton.Text = 'Clip Data';
            app.CreateLineButton = uibutton(app.analysisGrid, 'push');
            app.CreateLineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateLineButtonPushed, true);
            app.CreateLineButton.Text = 'Create Line';
            app.Batch = uipanel(app.BedformFinderUIFigure);
            app.batchGrid = uigridlayout(app.Batch);
            app.backHomeButton3 = uibutton(app.batchGrid, 'push');
            app.backHomeButton3.ButtonPushedFcn = createCallbackFcn(app, @backHomeButton1Pushed, true);
            app.backHomeButton3.Text = 'Back to Main';
            app.PleasechooseadatafolderButton = uibutton(app.batchGrid, 'push');
            app.PleasechooseadatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseadatafolderButtonPushed, true);
            app.PleasechooseadatafolderButton.Text = 'Please choose a data folder.';
            app.ExtDropDown_4 = uidropdown(app.batchGrid);
            app.ExtDropDown_4.Items = {'.mat', '.json', '.hdf5'};
            app.ExtDropDown_4.Value = '.mat';
            app.InputCRSDropDown_2 = uidropdown(app.batchGrid);
            app.InputCRSDropDown_2.Items = {'Other (EPSG)', 'Other (downloaded from eHydro)', 'NAD_1983_2011_StatePlane_New_York_East_FIPS_3101_Ft_US', 'NAD_1983_BLM_Zone_15N_ftUS', 'NAD_1983_BLM_Zone_16N_ftUS', 'NAD_1983_StatePlane_Arkansas_North_FIPS_0301_Feet', 'NAD_1983_StatePlane_Arkansas_South_FIPS_0302_Feet', 'NAD_1983_StatePlane_California_III_FIPS_0403_Feet', 'NAD_1983_StatePlane_California_II_FIPS_0402_Feet', 'NAD_1983_StatePlane_California_IV_FIPS_0404_Feet', 'NAD_1983_StatePlane_California_I_FIPS_0401_Feet', 'NAD_1983_StatePlane_California_VI_FIPS_0406_Feet', 'NAD_1983_StatePlane_California_V_FIPS_0405_Feet', 'NAD_1983_StatePlane_Connecticut_FIPS_0600_Feet', 'NAD_1983_StatePlane_Delaware_FIPS_0700_Feet', 'NAD_1983_StatePlane_Florida_East_FIPS_0901_Feet', 'NAD_1983_StatePlane_Florida_North_FIPS_0903_Feet', 'NAD_1983_StatePlane_Florida_West_FIPS_0902_Feet', 'NAD_1983_StatePlane_Georgia_East_FIPS_1001_Feet', 'NAD_1983_StatePlane_Illinois_East_FIPS_1201_Feet', 'NAD_1983_StatePlane_Illinois_West_FIPS_1202_Feet', 'NAD_1983_StatePlane_Indiana_West_FIPS_1302_Feet', 'NAD_1983_StatePlane_Iowa_North_FIPS_1401_Feet', 'NAD_1983_StatePlane_Iowa_North_FIPS_1401_IntlFeet', 'NAD_1983_StatePlane_Kentucky_North_FIPS_1601_Feet', 'NAD_1983_StatePlane_Kentucky_South_FIPS_1602_Feet', 'NAD_1983_StatePlane_Louisiana_South_FIPS_1702_Feet', 'NAD_1983_StatePlane_Maine_East_FIPS_1801_Feet', 'NAD_1983_StatePlane_Maine_West_FIPS_1802_Feet', 'NAD_1983_StatePlane_Maryland_FIPS_1900_Feet', 'NAD_1983_StatePlane_Massachusetts_Island_FIPS_2002_Feet', 'NAD_1983_StatePlane_Massachusetts_Mainland_FIPS_2001_Feet', 'NAD_1983_StatePlane_Minnesota_North_FIPS_2201_Feet', 'NAD_1983_StatePlane_Minnesota_South_FIPS_2203_Feet', 'NAD_1983_StatePlane_Minnesota_South_FIPS_2203_IntlFeet', 'NAD_1983_StatePlane_Mississippi_East_FIPS_2301_Feet', 'NAD_1983_StatePlane_Mississippi_West_FIPS_2302_Feet', 'NAD_1983_StatePlane_New_Hampshire_FIPS_2800_Feet', 'NAD_1983_StatePlane_New_Jersey_FIPS_2900_Feet', 'NAD_1983_StatePlane_New_York_Central_FIPS_3102_Feet', 'NAD_1983_StatePlane_New_York_East_FIPS_3101_Feet', 'NAD_1983_StatePlane_New_York_Long_Island_FIPS_3104_Feet', 'NAD_1983_StatePlane_New_York_West_FIPS_3103_Feet', 'NAD_1983_StatePlane_North_Carolina_FIPS_3200_Feet', 'NAD_1983_StatePlane_Ohio_North_FIPS_3401_Feet', 'NAD_1983_StatePlane_Ohio_South_FIPS_3402_Feet', 'NAD_1983_StatePlane_Oklahoma_North_FIPS_3501_Feet', 'NAD_1983_StatePlane_Pennsylvania_North_FIPS_3701_Feet', 'NAD_1983_StatePlane_Pennsylvania_South_FIPS_3702_Feet', 'NAD_1983_StatePlane_Rhode_Island_FIPS_3800_Feet', 'NAD_1983_StatePlane_South_Carolina_FIPS_3900_Feet_Intl', 'NAD_1983_StatePlane_Tennessee_FIPS_4100_Feet', 'NAD_1983_StatePlane_Texas_South_Central_FIPS_4204_Feet', 'NAD_1983_StatePlane_Texas_South_FIPS_4205_Feet', 'NAD_1983_StatePlane_Virginia_North_FIPS_4501_Feet', 'NAD_1983_StatePlane_Virginia_South_FIPS_4502_Feet', 'NAD_1983_StatePlane_Washington_North_FIPS_4601_Feet', 'NAD_1983_StatePlane_Washington_South_FIPS_4602_Feet', 'NAD_1983_StatePlane_West_Virginia_North_FIPS_4701_Feet', 'NAD_1983_StatePlane_West_Virginia_South_FIPS_4702_Feet', 'NAD_1983_StatePlane_Wisconsin_Central_FIPS_4802_Feet', 'NAD_1983_StatePlane_Wisconsin_North_FIPS_4801_Feet', 'NAD_1983_StatePlane_Wisconsin_South_FIPS_4803_Feet'};
            app.InputCRSDropDown_2.ValueChangedFcn = createCallbackFcn(app, @InputCRSDropDown_2ValueChanged, true);
            app.InputCRSDropDown_2.Value = 'Other (EPSG)';
            app.EPSGEditField_2 = uieditfield(app.batchGrid, 'numeric');
            app.EPSGEditField_2.Limits = [1 Inf];
            app.EPSGEditField_2.RoundFractionalValues = 'on';
            app.EPSGEditField_2.ValueDisplayFormat = '%11.4d';
            app.EPSGEditField_2.AllowEmpty = 'on';
            app.EPSGEditField_2.Placeholder = 'Ex: 1111';
            app.EPSGEditField_2.Value = [];
            app.VerticalUnitsDropDown_2 = uidropdown(app.batchGrid);
            app.VerticalUnitsDropDown_2.Items = {'meters', 'US survey feet'};
            app.VerticalUnitsDropDown_2.Value = 'US survey feet';
            app.VerticalTypeDropDown_2 = uidropdown(app.batchGrid);
            app.VerticalTypeDropDown_2.Items = {'Depth', 'Elevation'};
            app.VerticalTypeDropDown_2.Value = 'Depth';
            app.RunButton = uibutton(app.batchGrid, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.SampleSpacingEditField_2 = uieditfield(app.batchGrid, 'numeric');
            app.SampleSpacingEditField_2.Limits = [1e-05 Inf];
            app.SampleSpacingEditField_2.Value = 1;
            app.OffsetDistanceEditField_2 = uieditfield(app.batchGrid, 'numeric');
            app.OffsetDistanceEditField_2.Limits = [0.01 Inf];
            app.OffsetDistanceEditField_2.Value = 1;
            app.ProminenceEditField_2 = uieditfield(app.batchGrid, 'numeric');
            app.ProminenceEditField_2.Limits = [0 Inf];
            app.ProminenceEditField_2.Value = 0.0675;
            app.ThresholdHPFEditField_2 = uieditfield(app.batchGrid, 'numeric');
            app.ThresholdHPFEditField_2.Limits = [1 Inf];
            app.ThresholdHPFEditField_2.Value = 100;
            app.OffsetDistanceAutoButton_2 = uibutton(app.batchGrid, 'state');
            app.OffsetDistanceAutoButton_2.ValueChangedFcn = createCallbackFcn(app, @OffsetDistanceAutoButton_2ValueChanged, true);
            app.OffsetDistanceAutoButton_2.Text = 'Auto';
            app.SampleSpacingAutoButton_2 = uibutton(app.batchGrid, 'state');
            app.SampleSpacingAutoButton_2.ValueChangedFcn = createCallbackFcn(app, @SampleSpacingAutoButton_2ValueChanged, true);
            app.SampleSpacingAutoButton_2.Text = 'Auto';
            app.ProminenceAutoButton_2 = uibutton(app.batchGrid, 'state');
            app.ProminenceAutoButton_2.ValueChangedFcn = createCallbackFcn(app, @ProminenceAutoButton_2ValueChanged, true);
            app.ProminenceAutoButton_2.Text = 'Auto';
            app.ThresholdAutoButton_2 = uibutton(app.batchGrid, 'state');
            app.ThresholdAutoButton_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdAutoButton_2ValueChanged, true);
            app.ThresholdAutoButton_2.Text = 'Auto';
            app.batchLog = uihtml(app.batchGrid);
            app.batchLog.HTMLSource = ' ';
            app.DefaultButton_5 = uibutton(app.batchGrid, 'push');
            app.DefaultButton_5.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_5Pushed, true);
            app.DefaultButton_5.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton_5.Text = '';
            app.InputCRSLabel_2 = uilabel(app.batchGrid);
            app.InputCRSLabel_2.Text = 'Input CRS:';
            app.PleasechooseareferencelineButton_2 = uibutton(app.batchGrid, 'push');
            app.PleasechooseareferencelineButton_2.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseareferencelineButton_2Pushed, true);
            app.PleasechooseareferencelineButton_2.Text = 'Please choose a reference line.';
            app.RiverLineDropDown_2 = uidropdown(app.batchGrid);
            app.RiverLineDropDown_2.Items = {'Other (File)', 'Missouri (MO)'};
            app.RiverLineDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RiverLineDropDown_2ValueChanged, true);
            app.RiverLineDropDown_2.Value = 'Other (File)';
            app.LineFileLabel_2 = uilabel(app.batchGrid);
            app.LineFileLabel_2.Text = 'Line File:';
            app.DataLabel = uilabel(app.batchGrid);
            app.DataLabel.Text = 'Data:';
            app.ExtLabel_3 = uilabel(app.batchGrid);
            app.ExtLabel_3.Text = 'Ext:';
            app.FolderLocationField_4 = uieditfield(app.batchGrid, 'text');
            app.FolderLocationField_4.Placeholder = 'Default';
            app.EPSGLabel_2 = uilabel(app.batchGrid);
            app.EPSGLabel_2.Text = 'EPSG:';
            app.OffsetDistancemLabel_2 = uilabel(app.batchGrid);
            app.OffsetDistancemLabel_2.Text = 'Offset Distance (m)';
            app.SampleSpacingmLabel_2 = uilabel(app.batchGrid);
            app.SampleSpacingmLabel_2.Text = 'Sample Spacing (m)';
            app.ProminencemLabel = uilabel(app.batchGrid);
            app.ProminencemLabel.Text = 'Prominence (m)';
            app.ThresholdHPFmLabel_2 = uilabel(app.batchGrid);
            app.ThresholdHPFmLabel_2.Text = 'ThresholdHPF (m)';
            app.StopButton = uibutton(app.batchGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed2, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.VerticalUnitsLabel_2 = uilabel(app.batchGrid);
            app.VerticalUnitsLabel_2.Text = 'Vertical Units:';
            app.VerticalTypeLabel_2 = uilabel(app.batchGrid);
            app.VerticalTypeLabel_2.Text = 'Vertical Type:';
            app.ButtonGroup = uibuttongroup(app.batchGrid);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FolderButton = uitogglebutton(app.ButtonGroup);
            app.FolderButton.Text = 'Folder';
            app.FolderButton.Value = true;
            app.FilesButton = uitogglebutton(app.ButtonGroup);
            app.FilesButton.Text = 'Files';
            app.Single = uipanel(app.BedformFinderUIFigure);
            app.singleGrid = uigridlayout(app.Single);
            app.backHomeButton2 = uibutton(app.singleGrid, 'push');
            app.backHomeButton2.ButtonPushedFcn = createCallbackFcn(app, @backHomeButton1Pushed, true);
            app.backHomeButton2.Text = 'Back to Main';
            app.PleasechooseadatafileButton = uibutton(app.singleGrid, 'push');
            app.PleasechooseadatafileButton.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseadatafileButtonPushed, true);
            app.PleasechooseadatafileButton.Text = 'Please choose a data file.';
            app.ExtDropDown = uidropdown(app.singleGrid);
            app.ExtDropDown.Items = {'.mat', '.json', '.hdf5'};
            app.ExtDropDown.Value = '.mat';
            app.EnterFilenameField = uieditfield(app.singleGrid, 'text');
            app.EnterFilenameField.CharacterLimits = [0 64];
            app.EnterFilenameField.Placeholder = 'Enter a data filename.';
            app.SaveDataButton = uibutton(app.singleGrid, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.SaveFiguresButton = uibutton(app.singleGrid, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Enable = 'off';
            app.SaveFiguresButton.Text = 'Save Figures';
            app.InputCRSDropDown = uidropdown(app.singleGrid);
            app.InputCRSDropDown.Items = {'Other (EPSG)', 'Other (downloaded from eHydro)', 'NAD_1983_2011_StatePlane_New_York_East_FIPS_3101_Ft_US', 'NAD_1983_BLM_Zone_15N_ftUS', 'NAD_1983_BLM_Zone_16N_ftUS', 'NAD_1983_StatePlane_Arkansas_North_FIPS_0301_Feet', 'NAD_1983_StatePlane_Arkansas_South_FIPS_0302_Feet', 'NAD_1983_StatePlane_California_III_FIPS_0403_Feet', 'NAD_1983_StatePlane_California_II_FIPS_0402_Feet', 'NAD_1983_StatePlane_California_IV_FIPS_0404_Feet', 'NAD_1983_StatePlane_California_I_FIPS_0401_Feet', 'NAD_1983_StatePlane_California_VI_FIPS_0406_Feet', 'NAD_1983_StatePlane_California_V_FIPS_0405_Feet', 'NAD_1983_StatePlane_Connecticut_FIPS_0600_Feet', 'NAD_1983_StatePlane_Delaware_FIPS_0700_Feet', 'NAD_1983_StatePlane_Florida_East_FIPS_0901_Feet', 'NAD_1983_StatePlane_Florida_North_FIPS_0903_Feet', 'NAD_1983_StatePlane_Florida_West_FIPS_0902_Feet', 'NAD_1983_StatePlane_Georgia_East_FIPS_1001_Feet', 'NAD_1983_StatePlane_Illinois_East_FIPS_1201_Feet', 'NAD_1983_StatePlane_Illinois_West_FIPS_1202_Feet', 'NAD_1983_StatePlane_Indiana_West_FIPS_1302_Feet', 'NAD_1983_StatePlane_Iowa_North_FIPS_1401_Feet', 'NAD_1983_StatePlane_Iowa_North_FIPS_1401_IntlFeet', 'NAD_1983_StatePlane_Kentucky_North_FIPS_1601_Feet', 'NAD_1983_StatePlane_Kentucky_South_FIPS_1602_Feet', 'NAD_1983_StatePlane_Louisiana_South_FIPS_1702_Feet', 'NAD_1983_StatePlane_Maine_East_FIPS_1801_Feet', 'NAD_1983_StatePlane_Maine_West_FIPS_1802_Feet', 'NAD_1983_StatePlane_Maryland_FIPS_1900_Feet', 'NAD_1983_StatePlane_Massachusetts_Island_FIPS_2002_Feet', 'NAD_1983_StatePlane_Massachusetts_Mainland_FIPS_2001_Feet', 'NAD_1983_StatePlane_Minnesota_North_FIPS_2201_Feet', 'NAD_1983_StatePlane_Minnesota_South_FIPS_2203_Feet', 'NAD_1983_StatePlane_Minnesota_South_FIPS_2203_IntlFeet', 'NAD_1983_StatePlane_Mississippi_East_FIPS_2301_Feet', 'NAD_1983_StatePlane_Mississippi_West_FIPS_2302_Feet', 'NAD_1983_StatePlane_New_Hampshire_FIPS_2800_Feet', 'NAD_1983_StatePlane_New_Jersey_FIPS_2900_Feet', 'NAD_1983_StatePlane_New_York_Central_FIPS_3102_Feet', 'NAD_1983_StatePlane_New_York_East_FIPS_3101_Feet', 'NAD_1983_StatePlane_New_York_Long_Island_FIPS_3104_Feet', 'NAD_1983_StatePlane_New_York_West_FIPS_3103_Feet', 'NAD_1983_StatePlane_North_Carolina_FIPS_3200_Feet', 'NAD_1983_StatePlane_Ohio_North_FIPS_3401_Feet', 'NAD_1983_StatePlane_Ohio_South_FIPS_3402_Feet', 'NAD_1983_StatePlane_Oklahoma_North_FIPS_3501_Feet', 'NAD_1983_StatePlane_Pennsylvania_North_FIPS_3701_Feet', 'NAD_1983_StatePlane_Pennsylvania_South_FIPS_3702_Feet', 'NAD_1983_StatePlane_Rhode_Island_FIPS_3800_Feet', 'NAD_1983_StatePlane_South_Carolina_FIPS_3900_Feet_Intl', 'NAD_1983_StatePlane_Tennessee_FIPS_4100_Feet', 'NAD_1983_StatePlane_Texas_South_Central_FIPS_4204_Feet', 'NAD_1983_StatePlane_Texas_South_FIPS_4205_Feet', 'NAD_1983_StatePlane_Virginia_North_FIPS_4501_Feet', 'NAD_1983_StatePlane_Virginia_South_FIPS_4502_Feet', 'NAD_1983_StatePlane_Washington_North_FIPS_4601_Feet', 'NAD_1983_StatePlane_Washington_South_FIPS_4602_Feet', 'NAD_1983_StatePlane_West_Virginia_North_FIPS_4701_Feet', 'NAD_1983_StatePlane_West_Virginia_South_FIPS_4702_Feet', 'NAD_1983_StatePlane_Wisconsin_Central_FIPS_4802_Feet', 'NAD_1983_StatePlane_Wisconsin_North_FIPS_4801_Feet', 'NAD_1983_StatePlane_Wisconsin_South_FIPS_4803_Feet'};
            app.InputCRSDropDown.ValueChangedFcn = createCallbackFcn(app, @InputCRSDropDownValueChanged, true);
            app.InputCRSDropDown.Value = 'Other (EPSG)';
            app.EPSGLabel = uilabel(app.singleGrid);
            app.EPSGLabel.Text = 'EPSG:';
            app.EPSGEditField = uieditfield(app.singleGrid, 'numeric');
            app.EPSGEditField.Limits = [1 Inf];
            app.EPSGEditField.RoundFractionalValues = 'on';
            app.EPSGEditField.ValueDisplayFormat = '%11.4d';
            app.EPSGEditField.AllowEmpty = 'on';
            app.EPSGEditField.Placeholder = 'Ex: 1111';
            app.EPSGEditField.Value = [];
            app.VerticalUnitsLabel = uilabel(app.singleGrid);
            app.VerticalUnitsLabel.Text = 'Vertical Units:';
            app.VerticalUnitsDropDown = uidropdown(app.singleGrid);
            app.VerticalUnitsDropDown.Items = {'meters', 'US survey feet'};
            app.VerticalUnitsDropDown.Value = 'US survey feet';
            app.VerticalTypeLabel = uilabel(app.singleGrid);
            app.VerticalTypeLabel.Text = 'Vertical Type:';
            app.VerticalTypeDropDown = uidropdown(app.singleGrid);
            app.VerticalTypeDropDown.Items = {'Depth', 'Elevation'};
            app.VerticalTypeDropDown.Value = 'Depth';
            app.ComputeButton = uibutton(app.singleGrid, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.SampleSpacingmLabel = uilabel(app.singleGrid);
            app.SampleSpacingmLabel.Text = 'Sample Spacing (m)';
            app.SampleSpacingEditField = uieditfield(app.singleGrid, 'numeric');
            app.SampleSpacingEditField.Limits = [1e-05 Inf];
            app.SampleSpacingEditField.Value = 1;
            app.OffsetDistancemLabel = uilabel(app.singleGrid);
            app.OffsetDistancemLabel.Text = 'Offset Distance (m)';
            app.OffsetDistanceEditField = uieditfield(app.singleGrid, 'numeric');
            app.OffsetDistanceEditField.Limits = [0.01 Inf];
            app.OffsetDistanceEditField.Value = 1;
            app.ProminencemEditFieldLabel = uilabel(app.singleGrid);
            app.ProminencemEditFieldLabel.Text = 'Prominence (m)';
            app.ProminenceEditField = uieditfield(app.singleGrid, 'numeric');
            app.ProminenceEditField.Limits = [0 Inf];
            app.ProminenceEditField.Value = 0.0675;
            app.ThresholdHPFmLabel = uilabel(app.singleGrid);
            app.ThresholdHPFmLabel.Text = 'ThresholdHPF (m)';
            app.ThresholdHPFEditField = uieditfield(app.singleGrid, 'numeric');
            app.ThresholdHPFEditField.Limits = [1 Inf];
            app.ThresholdHPFEditField.Value = 100;
            app.OffsetDistanceAutoButton = uibutton(app.singleGrid, 'state');
            app.OffsetDistanceAutoButton.ValueChangedFcn = createCallbackFcn(app, @OffsetDistanceAutoButtonValueChanged, true);
            app.OffsetDistanceAutoButton.Text = 'Auto';
            app.SampleSpacingAutoButton = uibutton(app.singleGrid, 'state');
            app.SampleSpacingAutoButton.ValueChangedFcn = createCallbackFcn(app, @SampleSpacingAutoButtonValueChanged, true);
            app.SampleSpacingAutoButton.Text = 'Auto';
            app.ProminenceAutoButton = uibutton(app.singleGrid, 'state');
            app.ProminenceAutoButton.ValueChangedFcn = createCallbackFcn(app, @ProminenceAutoButtonValueChanged, true);
            app.ProminenceAutoButton.Text = 'Auto';
            app.ThresholdAutoButton = uibutton(app.singleGrid, 'state');
            app.ThresholdAutoButton.ValueChangedFcn = createCallbackFcn(app, @ThresholdAutoButtonValueChanged, true);
            app.ThresholdAutoButton.Text = 'Auto';
            app.singleLog = uihtml(app.singleGrid);
            app.singleLog.HTMLSource = ' ';
            app.DefaultButton_2 = uibutton(app.singleGrid, 'push');
            app.DefaultButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_2Pushed, true);
            app.DefaultButton_2.Icon = fullfile(pathToMLAPP, 'App Dependancies', 'Images', 'foldericon.png');
            app.DefaultButton_2.Text = '';
            app.InputCRSLabel = uilabel(app.singleGrid);
            app.InputCRSLabel.Text = 'Input CRS:';
            app.PleasechooseareferencelineButton = uibutton(app.singleGrid, 'push');
            app.PleasechooseareferencelineButton.ButtonPushedFcn = createCallbackFcn(app, @PleasechooseareferencelineButtonPushed, true);
            app.PleasechooseareferencelineButton.Text = 'Please choose a reference line.';
            app.RiverLineDropDown = uidropdown(app.singleGrid);
            app.RiverLineDropDown.Items = {'Other (File)', 'Missouri (MO)'};
            app.RiverLineDropDown.ValueChangedFcn = createCallbackFcn(app, @RiverLineDropDownValueChanged, true);
            app.RiverLineDropDown.Value = 'Other (File)';
            app.LineFileLabel = uilabel(app.singleGrid);
            app.LineFileLabel.Text = 'Line File:';
            app.DataFileLabel = uilabel(app.singleGrid);
            app.DataFileLabel.Text = 'Data File:';
            app.ExtLabel = uilabel(app.singleGrid);
            app.ExtLabel.Text = 'Ext:';
            app.ExtLabel_2 = uilabel(app.singleGrid);
            app.ExtLabel_2.Text = 'Ext:';
            app.ExtDropDown_2 = uidropdown(app.singleGrid);
            app.ExtDropDown_2.Items = {'.png', '.jpg', '.fig', '.pdf', '.tiff', '.bmp', '.eps'};
            app.ExtDropDown_2.Value = '.png';
            app.EnterFilenameField_2 = uieditfield(app.singleGrid, 'text');
            app.EnterFilenameField_2.CharacterLimits = [0 64];
            app.EnterFilenameField_2.Placeholder = 'Enter a figure filename.';
            app.FolderLocationField_2 = uieditfield(app.singleGrid, 'text');
            app.FolderLocationField_2.Placeholder = 'Default';
            % Show the figure after all components are created
            app.BedformFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BedformFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BedformFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BedformFinderUIFigure)
        end
    end
end